classdef PEPTtoolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        CFDTrajectoriesMenu             matlab.ui.container.Menu
        PEPTTrajectoriesMenu            matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        FiguresMenu                     matlab.ui.container.Menu
        VelocityMenu                    matlab.ui.container.Menu
        NPassesMenu                     matlab.ui.container.Menu
        OccupancyMenu                   matlab.ui.container.Menu
        DispersionMenu                  matlab.ui.container.Menu
        DisplacementMenu                matlab.ui.container.Menu
        MixingEffectivenessMenu         matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        VelocityMenu_2                  matlab.ui.container.Menu
        CFDVoxelsMenu_3                 matlab.ui.container.Menu
        PEPTVoxelsMenu_3                matlab.ui.container.Menu
        ParityplotMenu                  matlab.ui.container.Menu
        NPassesMenu_2                   matlab.ui.container.Menu
        CFDVoxelsMenu                   matlab.ui.container.Menu
        PEPTVoxelsMenu                  matlab.ui.container.Menu
        OccupancyMenu_2                 matlab.ui.container.Menu
        CFDVoxelsMenu_2                 matlab.ui.container.Menu
        PEPTVoxelsMenu_2                matlab.ui.container.Menu
        ParityplotMenu_2                matlab.ui.container.Menu
        TomographyMenu                  matlab.ui.container.Menu
        VelocityTom                     matlab.ui.container.Menu
        NPassesTom                      matlab.ui.container.Menu
        OccupancyTom                    matlab.ui.container.Menu
        DispersionTom                   matlab.ui.container.Menu
        DisplacementTom                 matlab.ui.container.Menu
        MixingEffectivenessTom          matlab.ui.container.Menu
        CleardataMenu                   matlab.ui.container.Menu
        AnalyseMenu                     matlab.ui.container.Menu
        CalculatevelocitiesMenu         matlab.ui.container.Menu
        CFDMenu                         matlab.ui.container.Menu
        PEPTMenu                        matlab.ui.container.Menu
        BinalgorithmMenu                matlab.ui.container.Menu
        CFDMenu_4                       matlab.ui.container.Menu
        PEPTMenu_4                      matlab.ui.container.Menu
        SettingsPanel                   matlab.ui.container.Panel
        TomographyframeratefpsEditField  matlab.ui.control.NumericEditField
        TomographyframeratefpsEditFieldLabel  matlab.ui.control.Label
        ZLabel_4                        matlab.ui.control.Label
        YLabel_4                        matlab.ui.control.Label
        XLabel_4                        matlab.ui.control.Label
        ZLabel_3                        matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        CFDLabel_3                      matlab.ui.control.Label
        PEPTLabel_3                     matlab.ui.control.Label
        PEPTTransposeX                  matlab.ui.container.ButtonGroup
        PEPTTransposeXz                 matlab.ui.control.RadioButton
        PEPTTransposeXy                 matlab.ui.control.RadioButton
        PEPTTransposeXx                 matlab.ui.control.RadioButton
        PEPTTransposeY                  matlab.ui.container.ButtonGroup
        PEPTTransposeYz                 matlab.ui.control.RadioButton
        PEPTTransposeYy                 matlab.ui.control.RadioButton
        PEPTTransposeYx                 matlab.ui.control.RadioButton
        PEPTTransposeZ                  matlab.ui.container.ButtonGroup
        PEPTTransposeZz                 matlab.ui.control.RadioButton
        PEPTTransposeZy                 matlab.ui.control.RadioButton
        PEPTTransposeZx                 matlab.ui.control.RadioButton
        CFDTransposeX                   matlab.ui.container.ButtonGroup
        CFDTransposeXz                  matlab.ui.control.RadioButton
        CFDTransposeXy                  matlab.ui.control.RadioButton
        CFDTransposeXx                  matlab.ui.control.RadioButton
        CFDTransposeY                   matlab.ui.container.ButtonGroup
        CFDTransposeYz                  matlab.ui.control.RadioButton
        CFDTransposeYy                  matlab.ui.control.RadioButton
        CFDTransposeYx                  matlab.ui.control.RadioButton
        CFDTransposeZ                   matlab.ui.container.ButtonGroup
        CFDTransposeZz                  matlab.ui.control.RadioButton
        CFDTransposeZy                  matlab.ui.control.RadioButton
        CFDTransposeZx                  matlab.ui.control.RadioButton
        TransposedataLabel              matlab.ui.control.Label
        PEPTLabel_2                     matlab.ui.control.Label
        CFDLabel_2                      matlab.ui.control.Label
        FlipdataLabel                   matlab.ui.control.Label
        FlipCFDz                        matlab.ui.control.CheckBox
        FlipCFDy                        matlab.ui.control.CheckBox
        FlipCFDx                        matlab.ui.control.CheckBox
        FlipPEPTz                       matlab.ui.control.CheckBox
        FlipPEPTy                       matlab.ui.control.CheckBox
        FlipPEPTx                       matlab.ui.control.CheckBox
        MaximumpassesforoutliersEditField  matlab.ui.control.NumericEditField
        MaximumpassesforoutliersEditFieldLabel  matlab.ui.control.Label
        DatacentrepointLabel            matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ycalibcfd                       matlab.ui.control.NumericEditField
        ycalibpept                      matlab.ui.control.NumericEditField
        zcalibcfd                       matlab.ui.control.NumericEditField
        PEPTLabel                       matlab.ui.control.Label
        CFDLabel                        matlab.ui.control.Label
        zcalibpept                      matlab.ui.control.NumericEditField
        xcalibpept                      matlab.ui.control.NumericEditField
        xcalibcfd                       matlab.ui.control.NumericEditField
        VoxelsalphaEditField            matlab.ui.control.NumericEditField
        VoxelsalphaEditFieldLabel       matlab.ui.control.Label
        FreesurfacecutoffmEditFieldLabel_2  matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        DispersiontimeintervalsEditField  matlab.ui.control.NumericEditField
        DispersiontimeintervalsEditFieldLabel  matlab.ui.control.Label
        DispersiontimemaxsEditField     matlab.ui.control.NumericEditField
        DispersiontimemaxsEditFieldLabel  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        SavitzkyGolayFramelengthEditField  matlab.ui.control.NumericEditField
        SavitzkyGolayFramelengthEditFieldLabel  matlab.ui.control.Label
        ofCFDdatatoreadEditField        matlab.ui.control.NumericEditField
        ofCFDdatatoreadEditFieldLabel   matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        DatasettingsLabel               matlab.ui.control.Label
        PlotsettingsLabel               matlab.ui.control.Label
        CalculatevelocitiesButton       matlab.ui.control.Button
        UpdateplotsButton               matlab.ui.control.Button
        ofPEPTdatatoreadEditField       matlab.ui.control.NumericEditField
        ofPEPTdatatoreadEditFieldLabel  matlab.ui.control.Label
        CFDdatacollectionstartsEditField  matlab.ui.control.NumericEditField
        CFDdatacollectionstartsEditFieldLabel  matlab.ui.control.Label
        FreesurfacecutoffmEditField     matlab.ui.control.NumericEditField
        FreesurfacecutoffmEditFieldLabel  matlab.ui.control.Label
        NumberofvoxelsEditField         matlab.ui.control.NumericEditField
        NumberofvoxelsEditFieldLabel    matlab.ui.control.Label
        CharacteristicLengthmEditField  matlab.ui.control.NumericEditField
        CharacteristicLengthmEditFieldLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        VelocityTab                     matlab.ui.container.Tab
        ThresholdEditField_4            matlab.ui.control.NumericEditField
        ThresholdEditField_4Label       matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        MaxLabel_3                      matlab.ui.control.Label
        MinLabel_3                      matlab.ui.control.Label
        ThresholdEditField_3            matlab.ui.control.NumericEditField
        ParityplotmaxEditField          matlab.ui.control.NumericEditField
        ParityplotEditField_2           matlab.ui.control.NumericEditField
        ParityplotEditField_2Label      matlab.ui.control.Label
        ColourbarmaxEditField_7         matlab.ui.control.NumericEditField
        ColourbarEditField_2            matlab.ui.control.NumericEditField
        ColourbarEditField_2Label       matlab.ui.control.Label
        PEPTLabelVelocity               matlab.ui.control.Label
        CFDLabelVelocity                matlab.ui.control.Label
        UIAxesParityVelocity            matlab.ui.control.UIAxes
        UIAxesPEPTVelocity              matlab.ui.control.UIAxes
        UIAxesCFDVelocity               matlab.ui.control.UIAxes
        NumberofPassesTab               matlab.ui.container.Tab
        ThresholdEditField_6            matlab.ui.control.NumericEditField
        ThresholdEditField_6Label       matlab.ui.control.Label
        CheckBox_5                      matlab.ui.control.CheckBox
        MaxLabel_4                      matlab.ui.control.Label
        MinLabel_4                      matlab.ui.control.Label
        ThresholdEditField_5            matlab.ui.control.NumericEditField
        ColourbarmaxNpasses             matlab.ui.control.NumericEditField
        ColourbarminNpasses             matlab.ui.control.NumericEditField
        ColourbarEditField_3Label       matlab.ui.control.Label
        PEPTLabelNPasses                matlab.ui.control.Label
        CFDLabelNPasses                 matlab.ui.control.Label
        UIAxesPEPTNPasses               matlab.ui.control.UIAxes
        UIAxesCFDNPasses                matlab.ui.control.UIAxes
        OccupancyTab                    matlab.ui.container.Tab
        LogscaleLabel                   matlab.ui.control.Label
        LogColorbarOcc                  matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        ThresholdEditField_2            matlab.ui.control.NumericEditField
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ParityplotmaxEditField_2        matlab.ui.control.NumericEditField
        ParityplotEditField             matlab.ui.control.NumericEditField
        ParityplotEditFieldLabel        matlab.ui.control.Label
        ColourbarmaxOcc                 matlab.ui.control.NumericEditField
        ColourbarminOcc                 matlab.ui.control.NumericEditField
        ColourbarEditFieldLabel         matlab.ui.control.Label
        PEPTLabelOccupancy              matlab.ui.control.Label
        CFDLabelOccupancy               matlab.ui.control.Label
        UIAxesParityOccupancy           matlab.ui.control.UIAxes
        UIAxesPEPTOccupancy             matlab.ui.control.UIAxes
        UIAxesCFDOccupancy              matlab.ui.control.UIAxes
        DispersionTab                   matlab.ui.container.Tab
        ThresholdEditField_8            matlab.ui.control.NumericEditField
        ThresholdEditField_8Label       matlab.ui.control.Label
        CheckBox_6                      matlab.ui.control.CheckBox
        MaxLabel_5                      matlab.ui.control.Label
        MinLabel_5                      matlab.ui.control.Label
        ThresholdEditField_7            matlab.ui.control.NumericEditField
        ParityplotEditField_3Label      matlab.ui.control.Label
        ParityplotmaxEditField_3        matlab.ui.control.NumericEditField
        ParityplotEditField_3           matlab.ui.control.NumericEditField
        ColourbarmaxEditField_4         matlab.ui.control.NumericEditField
        ColourbarEditField_4            matlab.ui.control.NumericEditField
        ColourbarEditField_4Label       matlab.ui.control.Label
        PEPTLabelDispersion             matlab.ui.control.Label
        CFDLabelDispersion              matlab.ui.control.Label
        UIAxesParityDispersion          matlab.ui.control.UIAxes
        UIAxesPEPTDispersion            matlab.ui.control.UIAxes
        UIAxesCFDDispersion             matlab.ui.control.UIAxes
        DisplacementTab                 matlab.ui.container.Tab
        ThresholdEditField_10           matlab.ui.control.NumericEditField
        ThresholdEditField_10Label      matlab.ui.control.Label
        CheckBox_7                      matlab.ui.control.CheckBox
        MaxLabel_6                      matlab.ui.control.Label
        MinLabel_6                      matlab.ui.control.Label
        ThresholdEditField_9            matlab.ui.control.NumericEditField
        ParityplotmaxEditField_4        matlab.ui.control.NumericEditField
        ParityplotEditField_4           matlab.ui.control.NumericEditField
        ParityplotEditField_4Label      matlab.ui.control.Label
        ColourbarmaxEditField_3         matlab.ui.control.NumericEditField
        ColourbarEditField_5            matlab.ui.control.NumericEditField
        ColourbarEditField_5Label       matlab.ui.control.Label
        PEPTLabelDisplacement           matlab.ui.control.Label
        CFDLabelDisplacement            matlab.ui.control.Label
        UIAxesParityDisplacement        matlab.ui.control.UIAxes
        UIAxesPEPTDisplacement          matlab.ui.control.UIAxes
        UIAxesCFDDisplacement           matlab.ui.control.UIAxes
        MixerEffectivenessTab           matlab.ui.container.Tab
        ThresholdEditField_12           matlab.ui.control.NumericEditField
        ThresholdEditField_12Label      matlab.ui.control.Label
        CheckBox_8                      matlab.ui.control.CheckBox
        MaxLabel_7                      matlab.ui.control.Label
        MinLabel_7                      matlab.ui.control.Label
        ThresholdEditField_11           matlab.ui.control.NumericEditField
        ParityplotmaxEditField_5        matlab.ui.control.NumericEditField
        ParityplotEditField_5           matlab.ui.control.NumericEditField
        ParityplotEditField_5Label      matlab.ui.control.Label
        ColourbarmaxEditField_2         matlab.ui.control.NumericEditField
        ColourbarEditField_6            matlab.ui.control.NumericEditField
        ColourbarEditField_6Label       matlab.ui.control.Label
        PEPTLabelMixEff                 matlab.ui.control.Label
        CFDLabelMixEff                  matlab.ui.control.Label
        UIAxesParityMixEff              matlab.ui.control.UIAxes
        UIAxesPEPTMixEff                matlab.ui.control.UIAxes
        UIAxesCFDMixEff                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CFDCalibrationVals
        CFDCartesian
        CFDData
        CFDFile
        CFDName
        CFDTraj
        CFDMappedF
        CFDMappedV
        CFDMixerEffectiveness
        CFDslice_xrange
        CFDslice_yrange
        CFDslice_zrange
        CFDgif_range_x
        CFDgif_range_y
        CFDgif_range_z
        CFDgif_order_x
        CFDgif_order_y
        CFDgif_order_z
        CFDVoxDispersionRate
        CFDVoxDispersionRateNaN
        CFDVoxDisplacementRate
        CFDVoxDisplacementRateNaN
        CFDVoxMixEff
        CFDVoxMixEffNorm
        CFDVoxMixEffNormNaN
        CFDVoxNPasses
        CFDVoxNPassesNaN
        CFDVoxOccupancy
        CFDVoxOccupancyNaN
        CFDVoxVelocity
        CFDVoxVelocityNaN
        ColourDataCFDVox
        ColourDataPEPTVox
        CFDRowRead = 100;
        DispTimeStep = 0.5;
        DispersionMaxT = 5;
        ExpHalves1
        ExpHalves2
        SimHalves1
        SimHalves2
        flippeptx = 0;
        flippepty = 0;
        flippeptz = 0;
        flipcfdx = 0;
        flipcfdy = 0;
        flipcfdz = 0;
        FrameLength = 25; % -- Moving window for Savitzky-Golay filter, must be odd
        gifFrameRate = 10;
        hlinkVelocity
        hlinkNPasses
        hlinkOccupancy
        hlinkDispersion
        hlinkDisplacement
        hlinkMixEff
        HeightCutOff = [];
        LoadCFD
        LoadPEPT
        LogScaleColorbar = 1;
        MaxP = 7;
        MaxParity = 1;
        MinP = 5;
        MinParity = 0;
        NPassVox
        NVox = 20;
        OutlierPassThreshold = 0;
        parity_occupancy
        parity_velocity
        parityMinVelocity = 0;
        parityMaxVelocity = 1;
        parityMinOccupancy = 0;
        parityMaxOccupancy = 1;
        parityMinDispersion = 0;
        parityMaxDispersion = 1;
        parityMinDisplacement = 0;
        parityMaxDisplacement = 1;
        parityMinMixEff = 0;
        parityMaxMixEff = 1;
        PEPTCalibrationVals
        PEPTCartesian
        PEPTData
        PEPTFile
        PEPTName
        PEPTMappedF
        PEPTMappedV
        PEPTMixerEffectiveness
        PEPTslice_xrange
        PEPTslice_yrange
        PEPTslice_zrange
        PEPTgif_range_x
        PEPTgif_range_y
        PEPTgif_range_z
        PEPTgif_order_x
        PEPTgif_order_y
        PEPTgif_order_z
        PEPTrowread = 100; %
        PEPTTraj
        PEPTVoxDispersionRate
        PEPTVoxDispersionRateNaN
        PEPTVoxDisplacementRate
        PEPTVoxDisplacementRateNaN
        PEPTVoxMixEff
        PEPTVoxMixEffNorm
        PEPTVoxMixEffNormNaN
        PEPTVoxNPasses
        PEPTVoxNPassesNaN
        PEPTVoxVelocity
        PEPTVoxVelocityNaN
        PEPTVoxOccupancy
        PEPTVoxOccupancyNaN
        Q_sim
        ResTimeVox
        RMSE_exp
        RMSE_sim
        Rotate
        RunDispersion = 1;
        SizeEquip = 0.3;
        ThresholdVel = 0;
        ThresholdVelmin = 0;
        ThresholdVelmax = 0;
        ThresholdNpass = 0;
        ThresholdNpassmin = 0;
        ThresholdNpassmax = 0;
        ThresholdOcc = 0;
        ThresholdOccmin = 0;
        ThresholdOccmax = 0;
        ThresholdDispersion = 0;
        ThresholdDispersionmin = 0;
        ThresholdDispersionmax = 0;
        ThresholdDisplacement = 0;
        ThresholdDisplacementmin = 0;
        ThresholdDisplacementmax = 0;
        ThresholdMixEff = 0;
        ThresholdMixEffmin = 0;
        ThresholdMixEffmax = 0;
        StdErrPEPT %standard deviation of PEPT data
        StartTime = 1;
        UMax = 0.08;
        UMin = 0.00;
        Errdivisions = 2;
        Npassmin = 0;
        Npassmax = 200;
        Occmin = 1e-4;
        Occmax = 1e-2;
        Dispersionmin = 0;
        Dispersionmax = 1e-3;
        Displacementmin = 0;
        Displacementmax = 0.1;
        Mixeffmin = 0;
        Mixeffmax = 0.1;
        PStep = 2;
        VoxelsAlpha = 0.2;
        xCalibCFD = 0;
        xCalibPEPT = 0;
        yCalibCFD = 0;
        yCalibPEPT = 0;
        zCalibCFD = 0;
        zCalibPEPT = 0;
    end

    methods (Access = private)

        % Loading data into app
        function CFDLoad(app)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [name, path] = uigetfile('*.csv','Select CFD raw data file to open (parcel id, t, x, y, z)','');
                if isequal(name,0)
                    app.LoadCFD = false;
                    return
                else
                    app.LoadCFD = true;
                end
                delete(f); %delete the dummy figure

                app.CFDFile = [path, name];
                opts = detectImportOptions(app.CFDFile);
                [~,app.CFDName,~] = fileparts(name);
                app.CFDTraj = readmatrix(app.CFDFile,'NumHeaderLines',opts.DataLines(1)-1,'Delimiter',string(opts.Delimiter));
                % Data should be parcel id, time, x, y, z
                % Check that this is true:
                isaninteger = @(x)isfinite(x) & x==floor(x);
                i=1;
                % maximum number of lines to read = 100;
                if size(app.CFDTraj(:,1),1) < 100
                    sizerd = size(app.CFDTraj(:,1),1);
                else
                    sizerd = 100;
                end
                while i < size(app.CFDTraj(1:sizerd,1),1)
                    if isaninteger(app.CFDTraj(i,1)) ~=1
                        message = sprintf('Unexpected parcel id inputs.\r\nPlease check that data columns are correct.');
                        uialert(app.UIFigure,message,'CFD import error','Icon','error');
                        app.LoadCFD = false;
                        return
                    end
                    i=i+1;
                end
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD import error','Icon','error');
                return
            end


        end
        function PEPTLoad(app)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [name, path] = uigetfile('*.csv','Select PEPT raw data file to open (t, x, y, z)','');
                if isequal(name,0)
                    app.LoadPEPT = false;
                    return
                else
                    app.LoadPEPT = true;
                end
                delete(f); %delete the dummy figure

                app.PEPTFile = [path, name];
                opts = detectImportOptions(app.PEPTFile);
                [~,app.PEPTName,~] = fileparts(name);
                app.PEPTTraj = readmatrix(app.PEPTFile,'NumHeaderLines',opts.DataLines(1)-1,'Delimiter',string(opts.Delimiter));
                % columns: parcel id, time, x, y, z
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT import error','Icon','error');
                return
            end
        end

        % Calibrating data and calculating velocities
        function CFDVelocity3(app)
            % Sort data first
            try
                CFDRawData = app.CFDTraj;
                CFDRawData = sortrows(CFDRawData,[1,2]);
                % Figure out units of time (s or ms)
                tDiff = diff(CFDRawData(:,2)); % finds the difference in time between steps

                CFDRawData = CFDRawData(tDiff>0.001,:); % This excludes rows with timesteps less than 1 ms.
                rowKey = (1:length(CFDRawData))';
                cellRow = rowKey(diff(CFDRawData(:,1))~=0)+1; % returns the row of the first time-step for each parcel id
                cellRow = [1;cellRow;length(CFDRawData)+1]; % first row counts as a parcel id. Maybe delete last row?

                if mean(tDiff(1:cellRow(2)-1))>1 % if true, then time units are in ms, if false; s.
                    CFDRawData(:,2) = CFDRawData(:,2)/1000; %converts ms to s
                end
                if abs(max(CFDRawData(:,3),[],'omitnan')-min(CFDRawData(:,3),[],'omitnan'))>app.SizeEquip
                    %if true, then x values are in mm, assumes y and z also
                    CFDRawData(:,3:5) = CFDRawData(:,3:5)/1000; %converts mm to m
                end
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD sorting error','Icon','error');
                return
            end

            try
                % Assume data is time, x, y, z
                % transposing data for x
                if app.CFDTransposeXx.Value ==1
                    x = CFDRawData(:,3);
                elseif app.CFDTransposeXy.Value ==1
                    x = CFDRawData(:,4);
                else
                    x = CFDRawData(:,5);
                end

                % transposing data for y
                if app.CFDTransposeYx.Value ==1
                    y = CFDRawData(:,3);
                elseif app.CFDTransposeYy.Value ==1
                    y = CFDRawData(:,4);
                else
                    y = CFDRawData(:,5);
                end

                % transposing data for z
                if app.CFDTransposeZx.Value ==1
                    z = CFDRawData(:,3);
                elseif app.CFDTransposeZy.Value ==1
                    z = CFDRawData(:,4);
                else
                    z = CFDRawData(:,5);
                end
                CFDRawData(:,3:5) = [x,y,z];
                % Flip xyz data
                if app.flipcfdx ==1
                    CFDRawData(:,3) = -CFDRawData(:,3);
                end
                if app.flipcfdy ==1
                    CFDRawData(:,4) = -CFDRawData(:,4);
                end
                if app.flipcfdz ==1
                    CFDRawData(:,5) = -CFDRawData(:,5);
                end

                % need to do the same for x,y,z position... maybe using
                % size_equip variable as indicator.
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD transpose error','Icon','error');
                return
            end
            try
                nParticles = length(cellRow)-1;
                CFDRawData(:,6:9) = NaN;

                % Savitzky-Golay filter
                Order = 2;
                Ft = 0.5; % Interpolation factor, 1/Ft points between points
                [~,g] = sgolay(Order,app.FrameLength);
                coeff = abs(pascal(app.FrameLength,1));
                Weights = coeff(app.FrameLength,:)./sum(coeff(app.FrameLength,:));

                for i = 1:nParticles %
                    % current array are the rows specific to each tracer,
                    % 2=t, 3=x, 4=y, 5=z
                    currentArray = CFDRawData(cellRow(i):cellRow(i+1)-1,:);
                    if size(currentArray,1) >2
                        % Time divisions
                        tmin = min(currentArray(:,2),[],'omitnan');
                        tmax = max(currentArray(:,2),[],'omitnan');
                        dt = Ft*(tmax-tmin)/(length(currentArray(:,2))-1);

                        % interpolant
                        F = griddedInterpolant(currentArray(:,2),currentArray(:,:));
                        querypoints = tmin:dt:tmax;
                        currentArray = F(querypoints);

                        if length(currentArray) > app.FrameLength

                            % calculate vx vy vz
                            xcv = conv(currentArray(:,3),1./-(dt) .*g(:,2),'same');
                            ycv = conv(currentArray(:,4),1./-(dt) .*g(:,2),'same');
                            zcv = conv(currentArray(:,5),1./-(dt) .*g(:,2),'same');

                            % v convolution of polynomial with differentiation filter
                            sgolayinterp = sgolayfilt([xcv,ycv,zcv],Order,app.FrameLength,Weights);
                            CFDRawData(cellRow(i):cellRow(i+1)-1,6:8) = interp1(currentArray(:,2),sgolayinterp,CFDRawData(cellRow(i):cellRow(i+1)-1,2),"nearest");

                            CFDRawData(cellRow(i):ceil(app.FrameLength/2),:) = NaN;
                            CFDRawData(cellRow(i+1)-1-ceil(app.FrameLength/2):cellRow(i+1)-1,:) = NaN;
                        else
                            CFDRawData(cellRow(i):cellRow(i+1)-1,6:8) = NaN;

                        end
                    end


                end

                CFDRawData(any(isnan(CFDRawData), 1), :) = [];
                CFDRawData = CFDRawData(CFDRawData(:,2)>app.StartTime,:);
                CFDRawData(:,9) = sqrt(CFDRawData(:,6).^2 + CFDRawData(:,7).^2 + CFDRawData(:,8).^2);

                x_calibration = (min(CFDRawData(:,3)) + (max(CFDRawData(:,3)) - min(CFDRawData(:,3)))/2 + app.xCalibCFD); % correction in x length(m)
                y_calibration = (min(CFDRawData(:,4)) + (max(CFDRawData(:,4)) - min(CFDRawData(:,4)))/2 + app.yCalibCFD); % height 0 centred on impeller %correction in y height (m)
                z_calibration = (min(CFDRawData(:,5)) + (max(CFDRawData(:,5)) - min(CFDRawData(:,5)))/2 + app.zCalibCFD); % correction in z diameter(m)

                CFDRawData(:,3) = CFDRawData(:,3) -x_calibration;
                CFDRawData(:,4) = CFDRawData(:,4) -y_calibration;
                CFDRawData(:,5) = CFDRawData(:,5) -z_calibration;

                app.CFDCalibrationVals = [x_calibration;y_calibration;z_calibration];
                app.CFDCartesian = [CFDRawData(:,3), CFDRawData(:,4), CFDRawData(:,5), CFDRawData(:,2),...
                    CFDRawData(:,6), CFDRawData(:,7), CFDRawData(:,8), CFDRawData(:,9),CFDRawData(:,1),];

                % columns: x, y, z, time, vx, vy, vz, vmag, parcel id,
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD velocity calculation error','Icon','error');
                return
            end
        end
        function PEPTVelocity3(app)
            try
                PEPTRawData = app.PEPTTraj;
                PEPTRawData = sortrows(PEPTRawData,1);
                tDiff = diff(PEPTRawData(:,1)); % finds the difference in time between steps
                PEPTRawData = PEPTRawData(tDiff>0,:);
                if mean(tDiff(1:1000))>1 % if true, then time units are in ms, if false; s.
                    PEPTRawData(:,1) = PEPTRawData(:,1)/1000; %converts ms to s
                end
                if abs(max(PEPTRawData(:,2),[],'omitnan')-min(PEPTRawData(:,2),[],'omitnan'))>app.SizeEquip
                    %if true, then x values are in mm, assumes y and z also
                    PEPTRawData(:,2:4) = PEPTRawData(:,2:4)/1000; %converts mm to m
                end

            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT sorting error','Icon','error');
                return
            end

            try
                % Assume time, x, y, z
                t = PEPTRawData(:,1);
                % transposing data for x
                if app.PEPTTransposeXx.Value ==1
                    x = PEPTRawData(:,2);
                elseif app.PEPTTransposeXy.Value ==1
                    x = PEPTRawData(:,3);
                else
                    x = PEPTRawData(:,4);
                end

                % transposing data for y
                if app.PEPTTransposeYx.Value ==1
                    y = PEPTRawData(:,2);
                elseif app.PEPTTransposeYy.Value ==1
                    y = PEPTRawData(:,3);
                else
                    y = PEPTRawData(:,4);
                end

                % transposing data for z
                if app.PEPTTransposeZx.Value ==1
                    z = PEPTRawData(:,2);
                elseif app.PEPTTransposeZy.Value ==1
                    z = PEPTRawData(:,3);
                else
                    z = PEPTRawData(:,4);
                end

                % flips x,y,z data
                if app.flippeptx ==1
                    x = -x;
                end
                if app.flippepty ==1
                    y = -y;
                end
                if app.flippeptz ==1
                    z = -z;
                end
            catch ME

                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT transpose error','Icon','error');
                return
            end

            try
                % Savitzky-Golay filter, maybe need to use a loop to change
                % framelength or dt since PEPT frequency decreases over experiment
                Order = 2;
                Ft = 0.5; % Interpolation factor, 1/Ft points between points
                [~,g] = sgolay(Order,app.FrameLength);
                coeff = abs(pascal(app.FrameLength,1));
                Weights = coeff(app.FrameLength,:)./sum(coeff(app.FrameLength,:));

                % Time divisions
                tmin = min(t,[],'omitnan');
                tmax = max(t,[],'omitnan');
                dt = Ft*(tmax-tmin)/(length(t)-1);

                F = griddedInterpolant(t,[t,x,y,z]);
                querypoints = tmin:dt:tmax;
                interpTXYZ = F(querypoints);

                xcv = conv(interpTXYZ(:,2),1./-(dt) .*g(:,2),'same');
                ycv = conv(interpTXYZ(:,3),1./-(dt) .*g(:,2),'same');
                zcv = conv(interpTXYZ(:,4),1./-(dt) .*g(:,2),'same');

                % calculate vx vy vz
                cartesian_velocity(:,1) = x; % x coords
                cartesian_velocity(:,2) = y; % y coords
                cartesian_velocity(:,3) = z; % z coords
                cartesian_velocity(:,4) = t; % time
                cartesian_velocity(:,5:7) = interp1(interpTXYZ(:,1),sgolayfilt([xcv,ycv,zcv],Order,app.FrameLength,Weights),t,"nearest");

                cartesian_velocity(1:ceil(app.FrameLength/2),:) = NaN;
                cartesian_velocity(end-ceil(app.FrameLength/2):end,:) = NaN; % removes datapoints calculated without full framelength

                cartesian_velocity(:,8) = sqrt(cartesian_velocity(:,5).^2 + cartesian_velocity(:,6).^2 + cartesian_velocity(:,7).^2); % calculates velocity magnitude

                x_calibration = (min(cartesian_velocity(:,1)) + (max(cartesian_velocity(:,1)) - min(cartesian_velocity(:,1)))/2 + app.xCalibPEPT); % correction in x length(m)
                y_calibration = (min(cartesian_velocity(:,2)) + (max(cartesian_velocity(:,2)) - min(cartesian_velocity(:,2)))/2 + app.yCalibPEPT); % height 0 centred on impeller %correction in y height (m)
                z_calibration = (min(cartesian_velocity(:,3)) + (max(cartesian_velocity(:,3)) - min(cartesian_velocity(:,3)))/2 + app.zCalibPEPT); % correction in z diameter(m)

                cartesian_velocity(:,1) = cartesian_velocity(:,1) - x_calibration;
                cartesian_velocity(:,2) = cartesian_velocity(:,2) - y_calibration;
                cartesian_velocity(:,3) = cartesian_velocity(:,3) - z_calibration;

                app.CFDCalibrationVals = [x_calibration;y_calibration;z_calibration];
                app.PEPTCartesian = cartesian_velocity; % assigns the array to the object (app.cartesian)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT velocity calculation error','Icon','error');
                return
            end
        end

        % Discretising the data and computing parameters
        function VoxelBinsCFD(app)
            try
                sz_equip = app.SizeEquip; n_vox = app.NVox; hcutoff = app.HeightCutOff; % delete data above this (y is height in PEPT)
                size_vox = (sz_equip)/(n_vox-1); % size of voxels within cube

                % Assume format is x y z t vx vy vz vmag ax ay az amag

                RowRead = floor(length(app.CFDCartesian).*0.01.*app.CFDRowRead); % Loads up to a specific row
                cartesian_velocity = app.CFDCartesian(1:RowRead,:);
                nans = isnan(cartesian_velocity(:,8));
                cartesian_velocity(nans,:) = []; % remove after tidying up.

                xidx = floor(cartesian_velocity(:,1)/size_vox); % rounds the data point to the nearest voxel
                xbin3 = xidx - min(xidx) + 1; % allocates a bin index (idx) to data points
                yidx = floor(cartesian_velocity(:,2)/size_vox); ybin3 = yidx - min(yidx) + 1;
                zidx = floor(cartesian_velocity(:,3)/size_vox); zbin3 = zidx - min(zidx) + 1;
                binidx = [xbin3 ybin3 zbin3];

                if ~isempty(hcutoff)
                    hbin_cutoff = floor(hcutoff/size_vox)-min(yidx)+1;
                end

                % Finding N passes

                vox_diff = abs(diff(binidx,1,1)); % finds difference in voxel index between points
                vox_diff(1,1,1) = 1; % defines first point as a pass
                [row_diff, ~] = find(vox_diff~=0); % finds rows where data is in a new voxel
                pass_rows = unique(sortrows(row_diff)) +1; % removes repeated entries
                pass_rows(1,1,1)=1; % defines first point as a pass
                pass_idx = binidx(pass_rows(1:end-1),:); % fills array with bin indexes for each pass

                t_vox = cartesian_velocity(pass_rows,4); % time stamp when data enters a new voxel
                parcelid_vox = cartesian_velocity(pass_rows,9); % parcel id when data enters a new voxel
                dt_rows = diff(t_vox); % residence time of each voxel pass
                dt_rows(diff(parcelid_vox)>0) = 0; % if the parcel id between voxel passes is different, then the residence time is empty

                dt_vox = accumarray(pass_idx, dt_rows, [n_vox, n_vox, n_vox]);
                %N_passes = accumarray(pass_idx,1, [n_vox, n_vox, n_vox]); %
                % This method of calculating N passes is inaccurate because it
                % concatenates particle passes (overlaps trajectories and adds
                % an extra 2% passes.
                counts = accumarray(binidx, 1, [n_vox, n_vox, n_vox]); % counts freq of data points assigned to each voxel
                avg_vel = accumarray(binidx, cartesian_velocity(:,8), [n_vox, n_vox, n_vox])./counts; % sum of velocities in each voxel divided by number of data (average)
                t_res = dt_vox./cartesian_velocity(end,4);

                if ~isempty(hcutoff)
                    %N_passes(:,hbin_cutoff:end,:) = NaN;
                    avg_vel(:,hbin_cutoff:end,:) = NaN; % sum of velocities in each voxel divided by number of data (average)
                    t_res(:,hbin_cutoff:end,:) = NaN;
                end

                %N_passes(N_passes==0) = NaN;
                %app.CFDVoxNPasses = N_passes;
                app.CFDVoxVelocity = avg_vel;
                app.CFDVoxOccupancy = t_res;

                %% Dispersion computation
                DispersionRateVoxels = zeros([n_vox n_vox n_vox]); % initialise for accumarray
                DisplacementRateVoxels = zeros([n_vox n_vox n_vox]); % initialise for accumarray
                countN = zeros([n_vox n_vox n_vox]);
                totalPasses = zeros([n_vox n_vox n_vox]);
                DispersionTime = app.DispTimeStep:app.DispTimeStep:app.DispersionMaxT; % seconds
                rowKey = (1:length(cartesian_velocity))';
                cellRow = rowKey(diff(cartesian_velocity(:,9))~=0)+1; % returns the row of the first time-step for each parcel id
                cellRow = [1;cellRow;length(cartesian_velocity)+1]; % first row counts as a parcel id. Maybe delete last row?
                nParticles = length(cellRow)-1;
                passcounter = 0;
                n = 1; %initialising loop
                while n <= nParticles % need a while loop in order to skip particles with bad quality data
                    currentArray = cartesian_velocity(cellRow(n):cellRow(n+1)-1,:);
                    currentBins = binidx(cellRow(n):cellRow(n+1)-1,:);
                    vox_diff = abs(diff(currentBins,1,1)); % finds difference in voxel index between points
                    vox_diff(1,1,1) = 1; % defines first point as a pass
                    [row_diff, ~] = find(vox_diff~=0); % finds rows where data is in a new voxel
                    pass_rows = unique(sortrows(row_diff)) +1; % removes repeated entries
                    pass_rows(1,1,1)=1; % defines first point as a pass
                    pass_idx = currentBins(pass_rows(1:end-1),:); % fills array with bin indexes for each pass
                    passcounter = passcounter + size(pass_idx,1);

                    new_time = currentArray(pass_rows(1:end-1),4) + DispersionTime; % list of time where data enters a new bin + dispersion time
                    last_row_tdisp = find(new_time(:,length(DispersionTime)) < currentArray(end,4),1,'last'); % finds the row 'n' seconds before the end of data

                    % use a knnsearch with a K-D tree
                    kdtree = KDTreeSearcher(currentArray(:,4));

                    % initialise empty vector
                    DispersionRowArray = zeros(size(new_time));

                    % Vector new_time contains the time in 'n' seconds after a particle
                    % has entered a cell. where 'n' is the dispersion time.
                    % The KDTree method searches through cartesian_velocity for the rows corresponding
                    % to the time in each row of the new_time vector. The row indices
                    % are stored in vector row_idx_disp.

                    for i = 1:length(DispersionTime)
                        [DispersionRowArray(:,i),~] = knnsearch(kdtree,new_time(:,i));
                    end
                    DispersionRowArray(last_row_tdisp+1:end,:)=[];
                    if size(DispersionRowArray,1) > 1
                        x_disp_old = currentArray(pass_rows(1:size(DispersionRowArray,1)),1); % x position of tracer at time t
                        y_disp_old = currentArray(pass_rows(1:size(DispersionRowArray,1)),2); % y position of tracer at time t
                        z_disp_old = currentArray(pass_rows(1:size(DispersionRowArray,1)),3); % z position of tracer at time t

                        x_disp_new = reshape(currentArray(DispersionRowArray,1),size(DispersionRowArray)); % x position of tracer at time t + t_disp
                        y_disp_new = reshape(currentArray(DispersionRowArray,2),size(DispersionRowArray)); % y position of tracer at time t + t_disp
                        z_disp_new = reshape(currentArray(DispersionRowArray,3),size(DispersionRowArray)); % z position of tracer at time t + t_disp

                        pass_idx_refmat = split(sprintf('%02d%02d%02d,',[pass_idx(:,1).';pass_idx(:,2).';pass_idx(:,3).']),','); % reformatted pass index to single vector
                        [~,~,groupid] = unique(pass_idx_refmat,'stable');

                        dupebin = accumarray(groupid(1:size(DispersionRowArray,1)), (1:size(DispersionRowArray,1))', [], @(x){x});

                        % Dispersion calculation using the Two-Pass algorithm for variance
                        % find which rows correspond to the same bin
                        x_disp_avg = zeros(size(DispersionRowArray,1),length(DispersionTime));
                        y_disp_avg = zeros(size(DispersionRowArray,1),length(DispersionTime));
                        z_disp_avg = zeros(size(DispersionRowArray,1),length(DispersionTime));

                        for j = 1:length(DispersionTime)
                            for i = 1:length(dupebin) % cannot go to end of dataset because of the additional time added
                                x_array = x_disp_new(dupebin{i},j);
                                y_array = y_disp_new(dupebin{i},j);
                                z_array = z_disp_new(dupebin{i},j);
                                x_disp_avg(dupebin{i},j) = (sum(x_array)./length(dupebin{i})); % each mean x position of tracers that passed through a bin
                                y_disp_avg(dupebin{i},j) = (sum(y_array)./length(dupebin{i})); % each mean y position of tracers that passed through a bin
                                z_disp_avg(dupebin{i},j) = (sum(z_array)./length(dupebin{i})); % each mean z position of tracers that passed through a bin
                            end
                        end

                        DispersionVector = (x_disp_new - x_disp_avg).^2 + (y_disp_new - y_disp_avg).^2 + (z_disp_new - z_disp_avg).^2; % Units m^2
                        [DispersionRate, ~] = gradient(DispersionVector,app.DispTimeStep); % calculates the central difference gradient between time-steps % Variance rate of each t in units m^2/s
                        DispersionRateAvg = mean(DispersionRate,2); % mean average along all the column time-steps, mean variance rate in units m2/s
                        %DispersionRateAvg = mean(diff(DispersionVector,1,2)./app.DispTimeStep,2);

                        DisplacementVector = ((x_disp_new - x_disp_old).^2 + (y_disp_new - y_disp_old).^2 + (z_disp_new - z_disp_old).^2).^(1/2); % distance between tracer at t + t_disp and t, units m
                        [DisplacementRate, ~] = gradient(DisplacementVector,app.DispTimeStep); % calculates the central difference gradient between time-steps, units m/s
                        DisplacementRateAvg = mean(DisplacementRate,2); % mean average along all the column time-steps, units m/s

                        %DisplacementRateAvg = mean(diff(DisplacementVector,1,2)./app.DispTimeStep,2);

                        % [DispersionRate, ~] = gradient(bin_distance,DispTimeStep);
                        % DispRavg = mean(DispersionRate,2);

                        DispersionRateParticles = accumarray(pass_idx(1:size(DispersionRateAvg,1),:), DispersionRateAvg, [n_vox, n_vox, n_vox], @mean); %
                        DisplacementRateParticles = accumarray(pass_idx(1:size(DisplacementRateAvg,1),:), DisplacementRateAvg, [n_vox, n_vox, n_vox], @mean); % Units
                        N_passes = accumarray(pass_idx,1, [n_vox, n_vox, n_vox]);
                        % This method of calculating N passes is much more accurate
                        % since it doesn't cause overlap in individual particle
                        % trajectories
                        totalPasses = totalPasses + N_passes;

                        % to prevent out-of-memory errors, must perform iterative average
                        % instead of creating a 4D matrix (nVox x nVox x nVox x nParticles)
                        % In order to compute an iterative average, only the voxels with
                        % non-zero data appended should be divided by the number of counts
                        countN(N_passes~=0) = countN(N_passes~=0) + 1; % keeps track of the number of times averaged per voxel

                        DispersionRateVoxels(N_passes~=0) = ((countN(N_passes~=0)-1).*DispersionRateVoxels(N_passes~=0)...
                            + DispersionRateParticles(N_passes~=0))./countN(N_passes~=0);

                        DisplacementRateVoxels(N_passes~=0) = ((countN(N_passes~=0)-1).*DisplacementRateVoxels(N_passes~=0)...
                            + DisplacementRateParticles(N_passes~=0))./countN(N_passes~=0);

                        n = n + 1;
                    else
                        n = n + 1;
                    end
                end

                if ~isempty(hcutoff)
                    totalPasses(:,hbin_cutoff:end,:) = NaN;
                    DispersionRateVoxels(:,hbin_cutoff:end,:) = NaN; % cutting off data above the free surface
                    DisplacementRateVoxels(:,hbin_cutoff:end,:) = NaN;
                end


                totalPasses(totalPasses==0) = NaN;

                MEVoxels = DispersionRateVoxels.*totalPasses./sum(totalPasses,'all','omitnan'); % Contribution to mixer effectiveness in each voxel, units m2/s
                MEVoxelsNorm = MEVoxels./sum(MEVoxels,'all','omitnan'); % Normalises such that the sum of contributions = 1.0, units -
                MixerEffectiveness = (1/sum(totalPasses,'all','omitnan')).*sum(DispersionRateVoxels.*totalPasses,'all','omitnan'); % total mixer effectiveness, units m2/s

                % Dispersion output parameters
                app.CFDVoxNPasses = totalPasses;
                app.CFDVoxDispersionRate = DispersionRateVoxels;
                app.CFDVoxDisplacementRate = DisplacementRateVoxels;
                app.CFDVoxMixEff = MEVoxels;
                app.CFDVoxMixEffNorm = MEVoxelsNorm;
                app.CFDMixerEffectiveness = MixerEffectiveness;

                occupied = counts > 0; % labels voxels with data inside
                oind = find(occupied); % returns the index of the voxels that are occupied

                [ox, oy, oz] = ind2sub(size(occupied), oind); % creates array subscripts of each occupied voxel

                oxc = (ox + min(xidx) - 1) * size_vox; % oxc, oyc, and ozc are the coordinate position of the occupied voxels
                oyc = (oy + min(yidx) - 1) * size_vox;
                ozc = (oz + min(zidx) - 1) * size_vox;
                noq = length(oxc); % the number of occupied voxels


                % https://uk.mathworks.com/help/matlab/visualize/multifaceted-patches.html
                % for more
                rF = [1 2 3 4 1; 8 7 6 5 8; 1 4 6 7 1; 2 8 5 3 2; 1 7 8 2 1; 3 5 6 4 3];  %in closed form % Order in which vertices are connected to draw faces
                rV = [0 0 0; 1 0 0; 1 0 1; 0 0 1; 1 1 1; 0 1 1; 0 1 0; 1 1 0] .* [size_vox, size_vox, size_vox]; % [x y z] coordinates of vertices on a voxel


                poxc = oxc(1:noq); poyc = oyc(1:noq); pozc = ozc(1:noq); % not sure if this is needed but it ensures every vector containing the voxel positions are the same length
                allF = repmat(rF(:,1:end-1), noq, 1) + 8*repelem((0:noq-1).', size(rF,1), 1); % repmat creates array of rF same size as noq (total number of faces for occupied voxels).
                % For each voxel (0:noq-1) every 6 faces (size rF) have 8 vertices (8*).
                % allF = cumulative number of vertices for each voxel.

                allV = repelem([poxc, poyc, pozc], size(rV,1), 1) + repmat(rV, noq, 1); % position of bottom corner vertex of each voxel

                [app.CFDMappedV, ~, Vidx] = unique(allV, 'rows'); % reorders the position of the unique vertices (because voxels can share vertices)
                % mapped V is the vertice coordinate position in space, while Vidx is the index of voxel
                app.CFDMappedF = Vidx(allF); % mappedF is the voxel index associated with each face

                ColIdxVel   = repelem(app.CFDVoxVelocity(oind),6);    % creates colour index based on average velocity in each voxel (figure 1)
                ColIdxNPass = repelem(app.CFDVoxNPasses(oind),6);   % creates colour index based on number of passes in each voxel (figure 2)
                ColIdxTRes = repelem(app.CFDVoxOccupancy(oind),6);      % creates colour index based on fraction of residence time in each voxel (figure 3)
                ColIdxDispersionR  = repelem(app.CFDVoxDispersionRate(oind),6);   % creates colour index based on dispersion value in each voxel (figure 4)
                ColIdxDisplaceR  = repelem(app.CFDVoxDisplacementRate(oind),6); % creates colour index based on displacement value in each voxel (figure 5)
                ColIdxMixEff = repelem(app.CFDVoxMixEff(oind),6);
                ColIdxMixEffNorm = repelem(app.CFDVoxMixEffNorm(oind),6);
                cdata = [ColIdxVel, ColIdxNPass, ColIdxTRes, ColIdxDispersionR,...
                    ColIdxDisplaceR, ColIdxMixEff, ColIdxMixEffNorm]; % color map data assigned as array (1 velocity, 2 N passes, 3 occupancy,
                %  4 dispersion, 5 displacement, 6 ME, 7 ME Norm)
                app.ColourDataCFDVox = cdata;

                %% Tomography
                app.CFDVoxVelocityNaN = app.CFDVoxVelocity; app.CFDVoxVelocityNaN(app.CFDVoxVelocityNaN == 0) = NaN; % wipe null data
                app.CFDVoxNPassesNaN = app.CFDVoxNPasses; app.CFDVoxNPassesNaN(app.CFDVoxNPassesNaN == 0) = NaN; % wipe null data
                app.CFDVoxOccupancyNaN = app.CFDVoxOccupancy; app.CFDVoxOccupancyNaN(app.CFDVoxOccupancyNaN == 0) = NaN; % wipe null data
                app.CFDVoxDispersionRateNaN = app.CFDVoxDispersionRate; app.CFDVoxDispersionRateNaN(app.CFDVoxDispersionRateNaN == 0) = NaN; % wipe null data
                app.CFDVoxDisplacementRateNaN = app.CFDVoxDisplacementRate; app.CFDVoxDisplacementRateNaN(app.CFDVoxDisplacementRateNaN == 0) = NaN; % wipe null data
                app.CFDVoxMixEffNormNaN = app.CFDVoxMixEffNorm; app.CFDVoxMixEffNormNaN(app.CFDVoxMixEffNormNaN == 0) = NaN; % wipe null data

                app.CFDslice_xrange = min(oxc):size_vox:min(oxc)+size_vox*(n_vox-1); % Range to compute slice calculation must equal size of accumarray (N bin x N bin x N bin)
                app.CFDslice_yrange = min(oyc):size_vox:min(oyc)+size_vox*(n_vox-1); % This ensures that there are always the correct number of voxels per direction
                app.CFDslice_zrange = min(ozc):size_vox:min(ozc)+size_vox*(n_vox-1);

                app.CFDgif_range_x = min(oxc):size_vox:max(oxc); % we want to cut the gif off where there is no more data
                app.CFDgif_range_y = min(oyc):size_vox:max(oyc);
                app.CFDgif_range_z = min(ozc):size_vox:max(ozc);

                app.CFDgif_order_x = [flip(app.CFDgif_range_x)';app.CFDgif_range_x']; % From top to bottom to top. This allows repeated gif
                app.CFDgif_order_y = [flip(app.CFDgif_range_y)';app.CFDgif_range_y'];
                app.CFDgif_order_z = [flip(app.CFDgif_range_z)';app.CFDgif_range_z'];
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD binning error','Icon','error');
                return
            end
        end
        function VoxelBinsPEPT(app)
            try
                sz_equip = app.SizeEquip; n_vox = app.NVox; hcutoff = app.HeightCutOff; % delete data above this (y is height in PEPT)
                size_vox = (sz_equip)/(n_vox-1); % size of voxels within cube
                % Assume format is x y z t vx vy vz vmag ax ay az amag

                RowRead = floor(length(app.PEPTCartesian).*0.01.*app.PEPTrowread); % Loads up to a specific row
                cartesian_velocity = app.PEPTCartesian(1:RowRead,:);
                nans = isnan(cartesian_velocity(:,8));
                cartesian_velocity(nans,:) = []; % remove after tidying up.

                xidx = floor(cartesian_velocity(:,1)/size_vox); % rounds the data point to the nearest voxel
                xbin3 = xidx - min(xidx) + 1; % allocates a bin index (idx) to data points
                yidx = floor(cartesian_velocity(:,2)/size_vox); ybin3 = yidx - min(yidx) + 1;
                zidx = floor(cartesian_velocity(:,3)/size_vox); zbin3 = zidx - min(zidx) + 1;
                binidx = [xbin3 ybin3 zbin3];

                if ~isempty(hcutoff)
                    hbin_cutoff = floor(hcutoff/size_vox)-min(yidx)+1;
                end


                % Finding N passes

                vox_diff = abs(diff(binidx,1,1)); % finds difference in voxel index between points
                vox_diff(1,1,1) = 1; % defines first point as a pass
                [row_diff, ~] = find(vox_diff~=0); % finds rows where data is in a new voxel
                pass_rows = unique(sortrows(row_diff)) +1; % removes repeated entries
                pass_rows(1,1,1)=1; % defines first point as a pass
                pass_idx = binidx(pass_rows(1:end-1),:); % fills array with bin indexes for each pass

                t_vox = cartesian_velocity(pass_rows,4); % time stamp when data enters a new voxel
                dt_rows = diff(t_vox); % residence time of each voxel pass

                % Dispersion
                DispersionTime = app.DispTimeStep:app.DispTimeStep:app.DispersionMaxT; % seconds
                new_time = cartesian_velocity(pass_rows(1:end-1),4) + DispersionTime; % list of time where data enters a new bin + dispersion time
                last_row_tdisp = find(new_time(:,length(DispersionTime)) < cartesian_velocity(end,4),1,'last'); % finds the row 'n' seconds before the end of data

                % use a knnsearch with a K-D tree
                kdtree = KDTreeSearcher(cartesian_velocity(:,4));

                % initialise empty vector
                DispersionRowArray = zeros(size(new_time));

                % Vector new_time contains the time in 'n' seconds after a particle
                % has entered a cell. where 'n' is the dispersion time.
                % The KDTree method searches through cartesian_velocity for the rows corresponding
                % to the time in each row of the new_time vector. The row indices
                % are stored in vector row_idx_disp.

                for i = 1:length(DispersionTime)
                    [DispersionRowArray(:,i),~] = knnsearch(kdtree,new_time(:,i));
                end
                DispersionRowArray(last_row_tdisp+1:end,:)=[];

                x_disp_old = cartesian_velocity(pass_rows(1:size(DispersionRowArray,1)),1); % x position of tracer at time t
                y_disp_old = cartesian_velocity(pass_rows(1:size(DispersionRowArray,1)),2); % y position of tracer at time t
                z_disp_old = cartesian_velocity(pass_rows(1:size(DispersionRowArray,1)),3); % z position of tracer at time t

                x_disp_new = reshape(cartesian_velocity(DispersionRowArray,1),size(DispersionRowArray)); % x position of tracer at time t + t_disp
                y_disp_new = reshape(cartesian_velocity(DispersionRowArray,2),size(DispersionRowArray)); % y position of tracer at time t + t_disp
                z_disp_new = reshape(cartesian_velocity(DispersionRowArray,3),size(DispersionRowArray)); % z position of tracer at time t + t_disp

                pass_idx_refmat = split(sprintf('%02d%02d%02d,',[pass_idx(:,1).';pass_idx(:,2).';pass_idx(:,3).']),','); % reformatted pass index to single vector
                [~,~,groupid] = unique(pass_idx_refmat,'stable');
                dupebin = accumarray(groupid(1:size(DispersionRowArray,1)), (1:size(DispersionRowArray,1))', [], @(x){x}); % group row indexes by distinct bins


                % Dispersion calculation using the Two-Pass algorithm for variance
                % find which rows correspond to the same bin

                x_disp_avg = zeros(size(DispersionRowArray,1),length(DispersionTime));
                y_disp_avg = zeros(size(DispersionRowArray,1),length(DispersionTime));
                z_disp_avg = zeros(size(DispersionRowArray,1),length(DispersionTime));

                for j = 1:length(DispersionTime)
                    for i = 1:length(dupebin) % cannot go to end of dataset because of the additional time added

                        x_array = x_disp_new(dupebin{i},j);
                        y_array = y_disp_new(dupebin{i},j);
                        z_array = z_disp_new(dupebin{i},j);

                        x_disp_avg(dupebin{i},j) = (sum(x_array)./length(dupebin{i})); % each mean x position of tracers that passed through a bin
                        y_disp_avg(dupebin{i},j) = (sum(y_array)./length(dupebin{i})); % each mean y position of tracers that passed through a bin
                        z_disp_avg(dupebin{i},j) = (sum(z_array)./length(dupebin{i})); % each mean z position of tracers that passed through a bin

                    end
                end

                DispersionVector = (x_disp_new - x_disp_avg).^2 + (y_disp_new - y_disp_avg).^2 + (z_disp_new - z_disp_avg).^2; % Units m^2
                [DispersionRate, ~] = gradient(DispersionVector,app.DispTimeStep); % calculates the central difference gradient between time-steps % Variance rate of each t in units m^2/s
                DispersionRateAvg = mean(DispersionRate,2); % mean average along all the column time-steps, mean variance rate in units m2/s
                %DispersionRateAvg = mean(diff(DispersionVector,1,2)./app.DispTimeStep,2);


                DisplacementVector = ((x_disp_new - x_disp_old).^2 + (y_disp_new - y_disp_old).^2 + (z_disp_new - z_disp_old).^2).^(1/2); % distance between tracer at t + t_disp and t, units m
                [DisplacementRate, ~] = gradient(DisplacementVector,app.DispTimeStep); % calculates the central difference gradient between time-steps, units m/s
                DisplacementRateAvg = mean(DisplacementRate,2); % mean average along all the column time-steps, units m/s
                %DisplacementRateAvg = mean(diff(DisplacementVector,1,2)./app.DispTimeStep,2);


                % Crafting voxels
                dt_vox = accumarray(pass_idx, dt_rows, [n_vox, n_vox, n_vox]);
                N_passes = accumarray(pass_idx,1, [n_vox, n_vox, n_vox]);
                counts = accumarray(binidx, 1, [n_vox, n_vox, n_vox]); % counts freq of data points assigned to each voxel
                avg_vel = accumarray(binidx, cartesian_velocity(:,8), [n_vox, n_vox, n_vox])./counts; % sum of velocities in each voxel divided by number of data (average)
                t_res = dt_vox./cartesian_velocity(end,4);
                DispersionRateVoxels = accumarray(pass_idx(1:length(DispersionRateAvg),:), DispersionRateAvg, [n_vox, n_vox, n_vox], @mean);
                DisplacementRateVoxels = accumarray(pass_idx(1:length(DisplacementRateAvg),:), DisplacementRateAvg, [n_vox, n_vox, n_vox], @mean); % Units
                MEVoxels = DispersionRateVoxels.*N_passes./sum(N_passes,'all','omitnan'); % Contribution to mixer effectiveness in each voxel, units m2/s
                MEVoxelsNorm = MEVoxels./sum(MEVoxels,'all','omitnan'); % Normalises such that the sum of contributions = 1.0, units -
                MixerEffectiveness = (1/sum(N_passes,'all','omitnan')).*sum(DispersionRateVoxels.*N_passes,'all','omitnan'); % total mixer effectiveness, units m2/s

                if ~isempty(hcutoff)
                    N_passes(:,hbin_cutoff:end,:) = NaN;
                    avg_vel(:,hbin_cutoff:end,:) = NaN; % sum of velocities in each voxel divided by number of data (average)
                    t_res(:,hbin_cutoff:end,:) = NaN;
                    DispersionRateVoxels(:,hbin_cutoff:end,:) = NaN;
                    DisplacementRateVoxels(:,hbin_cutoff:end,:) = NaN;
                    MEVoxels(:,hbin_cutoff:end,:) = NaN;
                    MEVoxelsNorm(:,hbin_cutoff:end,:) = NaN;

                end

                N_passes(N_passes==0) = NaN;
                app.PEPTVoxNPasses = N_passes;
                app.PEPTVoxVelocity = avg_vel;
                app.PEPTVoxOccupancy = t_res;
                app.PEPTVoxDispersionRate = DispersionRateVoxels;
                app.PEPTVoxDisplacementRate = DisplacementRateVoxels;
                app.PEPTVoxMixEff = MEVoxels;
                app.PEPTVoxMixEffNorm = MEVoxelsNorm;
                app.PEPTMixerEffectiveness = MixerEffectiveness;

                occupied = counts > 0; % labels voxels with data inside
                oind = find(occupied); % returns the index of the voxels that are occupied

                [ox, oy, oz] = ind2sub(size(occupied), oind); % creates array subscripts of each occupied voxel

                oxc = (ox + min(xidx) - 1) * size_vox; % oxc, oyc, and ozc are the coordinate position of the occupied voxels
                oyc = (oy + min(yidx) - 1) * size_vox;
                ozc = (oz + min(zidx) - 1) * size_vox;
                noq = length(oxc); % the number of occupied voxels


                % https://uk.mathworks.com/help/matlab/visualize/multifaceted-patches.html
                % for more
                rF = [1 2 3 4 1; 8 7 6 5 8; 1 4 6 7 1; 2 8 5 3 2; 1 7 8 2 1; 3 5 6 4 3];  %in closed form % Order in which vertices are connected to draw faces
                rV = [0 0 0; 1 0 0; 1 0 1; 0 0 1; 1 1 1; 0 1 1; 0 1 0; 1 1 0] .* [size_vox, size_vox, size_vox]; % [x y z] coordinates of vertices on a voxel


                poxc = oxc(1:noq); poyc = oyc(1:noq); pozc = ozc(1:noq); % not sure if this is needed but it ensures every vector containing the voxel positions are the same length
                allF = repmat(rF(:,1:end-1), noq, 1) + 8*repelem((0:noq-1).', size(rF,1), 1); % repmat creates array of rF same size as noq (total number of faces for occupied voxels).
                % For each voxel (0:noq-1) every 6 faces (size rF) have 8 vertices (8*).
                % allF = cumulative number of vertices for each voxel.

                allV = repelem([poxc, poyc, pozc], size(rV,1), 1) + repmat(rV, noq, 1); % position of bottom corner vertex of each voxel

                [app.PEPTMappedV, ~, Vidx] = unique(allV, 'rows'); % reorders the position of the unique vertices (because voxels can share vertices)
                % mapped V is the vertice coordinate position in space, while Vidx is the index of voxel
                app.PEPTMappedF = Vidx(allF); % mappedF is the voxel index associated with each face

                ColIdxVel   = repelem(app.PEPTVoxVelocity(oind),6);    % creates colour index based on average velocity in each voxel (figure 1)
                ColIdxNPass = repelem(app.PEPTVoxNPasses(oind),6);   % creates colour index based on number of passes in each voxel (figure 2)
                ColIdxTRes = repelem(app.PEPTVoxOccupancy(oind),6);      % creates colour index based on fraction of residence time in each voxel (figure 3)
                ColIdxDispersionR  = repelem(app.PEPTVoxDispersionRate(oind),6);   % creates colour index based on dispersion value in each voxel (figure 4)
                ColIdxDisplaceR  = repelem(app.PEPTVoxDisplacementRate(oind),6);
                ColIdxMixEff = repelem(app.PEPTVoxMixEff(oind),6);
                ColIdxMixEffNorm = repelem(app.PEPTVoxMixEffNorm(oind),6);
                cdata = [ColIdxVel, ColIdxNPass, ColIdxTRes, ColIdxDispersionR,...
                    ColIdxDisplaceR, ColIdxMixEff, ColIdxMixEffNorm]; % color map data assigned as array (1 velocity, 2 N passes, 3 occupancy,
                %  4 dispersion, 5 displacement, 6 ME, 7 ME Norm)
                app.ColourDataPEPTVox = cdata;

                %% Tomography
                app.PEPTVoxVelocityNaN = app.PEPTVoxVelocity; app.PEPTVoxVelocityNaN(app.PEPTVoxVelocityNaN == 0) = NaN; % wipe null data
                app.PEPTVoxNPassesNaN = app.PEPTVoxNPasses; app.PEPTVoxNPassesNaN(app.PEPTVoxNPassesNaN == 0) = NaN; % wipe null data
                app.PEPTVoxOccupancyNaN = app.PEPTVoxOccupancy; app.PEPTVoxOccupancyNaN(app.PEPTVoxOccupancyNaN == 0) = NaN; % wipe null data
                app.PEPTVoxDispersionRateNaN = app.PEPTVoxDispersionRate; app.PEPTVoxDispersionRateNaN(app.PEPTVoxDispersionRateNaN == 0) = NaN; % wipe null data
                app.PEPTVoxDisplacementRateNaN = app.PEPTVoxDisplacementRate; app.PEPTVoxDisplacementRateNaN(app.PEPTVoxDisplacementRateNaN == 0) = NaN; % wipe null data
                app.PEPTVoxMixEffNormNaN = app.PEPTVoxMixEffNorm; app.PEPTVoxMixEffNormNaN(app.PEPTVoxMixEffNormNaN == 0) = NaN; % wipe null data

                app.PEPTslice_xrange = min(oxc):size_vox:min(oxc)+size_vox*(n_vox-1); % Range to compute slice calculation must equal size of accumarray (N bin x N bin x N bin)
                app.PEPTslice_yrange = min(oyc):size_vox:min(oyc)+size_vox*(n_vox-1); % This ensures that there are always the correct number of voxels per direction
                app.PEPTslice_zrange = min(ozc):size_vox:min(ozc)+size_vox*(n_vox-1);

                app.PEPTgif_range_x = min(oxc):size_vox:max(oxc); % we want to cut the gif off where there is no more data
                app.PEPTgif_range_y = min(oyc):size_vox:max(oyc);
                app.PEPTgif_range_z = min(ozc):size_vox:max(ozc);

                app.PEPTgif_order_x = [flip(app.PEPTgif_range_x)';app.PEPTgif_range_x']; % From top to bottom to top. This allows repeated gif
                app.PEPTgif_order_y = [flip(app.PEPTgif_range_y)';app.PEPTgif_range_y'];
                app.PEPTgif_order_z = [flip(app.PEPTgif_range_z)';app.PEPTgif_range_z'];

            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT binning error','Icon','error');
                return
            end
        end
        function PEPTErr(app)
            Ndivisions = app.Errdivisions;
            sz_equip = app.SizeEquip; n_vox = app.NVox; hcutoff = app.HeightCutOff; % delete data above this (y is height in PEPT)
            size_vox = (sz_equip)/(n_vox-1); % size of voxels within cube
            % Assume format is x y z t vx vy vz vmag ax ay az amag

            RowRead = floor(length(app.PEPTCartesian).*0.01.*app.PEPTrowread); % Loads up to a specific row
            cartesian_velocity = app.PEPTCartesian(1:RowRead,:);
            nans = isnan(cartesian_velocity(:,8));
            cartesian_velocity(nans,:) = []; % remove after tidying up.

            xidx = floor(cartesian_velocity(:,1)/size_vox); % rounds the data point to the nearest voxel
            xbin3 = xidx - min(xidx) + 1; % allocates a bin index (idx) to data points
            yidx = floor(cartesian_velocity(:,2)/size_vox); ybin3 = yidx - min(yidx) + 1;
            zidx = floor(cartesian_velocity(:,3)/size_vox); zbin3 = zidx - min(zidx) + 1;
            binidx = [xbin3 ybin3 zbin3];

            if ~isempty(hcutoff)
                hbin_cutoff = floor(hcutoff/size_vox)-min(yidx)+1;
            end

            ErrMat = NaN(Ndivisions,n_vox,n_vox,n_vox);

            for n = 1:Ndivisions
                counts = accumarray(binidx((n-1)*floor(length(binidx)/Ndivisions)+1:n*floor(length(binidx)/Ndivisions),:),...
                    1, [n_vox, n_vox, n_vox]); % counts freq of data points assigned to each voxel

                avg_vel = accumarray(binidx((n-1)*floor(length(binidx)/Ndivisions)+1:n*floor(length(binidx)/Ndivisions),:),...
                    cartesian_velocity(((n-1)*floor(length(binidx)/Ndivisions)+1:n*floor(length(binidx)/Ndivisions)),8), [n_vox, n_vox, n_vox])./counts; % sum of velocities in each voxel divided by number of data (average)

                if ~isempty(hcutoff)
                    avg_vel(:,hbin_cutoff:end,:) = NaN;
                end

                ErrMat(n,:,:,:) = avg_vel;
            end
            % Simulation Quality statistic
            % RMSE simulation divided by RMSE experiment
            % Where RMSE experiment is a comparison of velocities in each
            % half of the experimental dataset
            % ErrMat(n,:,:,:) is E(a) and E(b)

            % first turn the matrix into two column vectors and compute the
            % square residuals.

            % remove zeros from data
            ErrMat(ErrMat==0) = NaN;

            % reshape 3D data into single column
            if Ndivisions <=2
                app.ExpHalves1 = reshape(ErrMat(1,:,:,:),[],1); %first half
                app.ExpHalves2 = reshape(ErrMat(2,:,:,:),[],1); %second half

            end
            
            % sum the square residuals, divided by number of non-nan cells
            app.StdErrPEPT = squeeze(std(ErrMat,0,1,'omitnan')); % units in m/s

        end
        function CFDErr(app)
            try
                sz_equip = app.SizeEquip; n_vox = app.NVox; hcutoff = app.HeightCutOff; % delete data above this (y is height in PEPT)
                size_vox = (sz_equip)/(n_vox-1); % size of voxels within cube

                % Assume format is x y z t vx vy vz vmag ax ay az amag

                RowRead = floor(length(app.CFDCartesian).*0.01.*app.CFDRowRead); % Loads up to a specific row
                cartesian_velocity = app.CFDCartesian(1:RowRead,:); % This already removed the data above the start time (before steady-state)
                nans = isnan(cartesian_velocity(:,8));
                cartesian_velocity(nans,:) = []; % remove after tidying up.

                % Determine segments
                % This random permutation causes the Qsim variable to
                % fluctuate... not sure if good or bad...
                % count unique ids
                ids = unique(cartesian_velocity(:,9));

                % randomly permute ids
                randids = ids(randperm(length(ids)));
                segsize = floor(0.5*length(randids)); % finding the size of each segment
                segment_ids = [randids(1:segsize), randids(segsize+1:2*segsize)]; %dividing the ids into two segments
                ErrMat = NaN(2,n_vox,n_vox,n_vox); % preallocating variables
                
                % for each segment
                for n = 1:2 %N segments
                    % index and match rows according to ids vector, then sort by
                    % id (9th col) and time (4th col)
                    segment = sortrows(cartesian_velocity(ismember(cartesian_velocity(:, 9), segment_ids(:,n)), :),[9,4]);

                    % for each segment
                    xidx = floor(segment(:,1)/size_vox); % rounds the data point to the nearest voxel
                    xbin3 = xidx - min(xidx) + 1; % allocates a bin index (idx) to data points
                    yidx = floor(segment(:,2)/size_vox); ybin3 = yidx - min(yidx) + 1;
                    zidx = floor(segment(:,3)/size_vox); zbin3 = zidx - min(zidx) + 1;
                    binidx = [xbin3 ybin3 zbin3];

                    if ~isempty(hcutoff)
                        hbin_cutoff = floor(hcutoff/size_vox)-min(yidx)+1;
                    end

                    counts = accumarray(binidx, 1, [n_vox, n_vox, n_vox]); % counts freq of data points assigned to each voxel
                    avg_vel = accumarray(binidx, segment(:,8), [n_vox, n_vox, n_vox])./counts; % sum of velocities in each voxel divided by number of data (average)

                    if ~isempty(hcutoff)
                        avg_vel(:,hbin_cutoff:end,:) = NaN; % sum of velocities in each voxel divided by number of data (average)
                    end

                    ErrMat(n,:,:,:) = avg_vel; % stores segment voxel velocities into a 4D array

                end
                    
                    app.SimHalves1 = reshape(ErrMat(1,:,:,:),[],1); %first half
                    app.SimHalves2 = reshape(ErrMat(2,:,:,:),[],1); %second half

            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD error error','Icon','error');
                return
            end

        end
        function SimulationQuality(app)
            exp1 = app.ExpHalves1; exp2 = app.ExpHalves2; sim1 = app.SimHalves1; sim2 = app.SimHalves2;

            % Compute root mean square error of experiment segments to
            % eachother
            app.RMSE_exp = sqrt(sum((exp1 - exp2).^2,'omitnan')./(sum(~isnan(exp1-exp2))));
            
            % Compute root mean square error of experiment-simulation
            % combinations
            % weighted sum of exp-sim errors
            % exp 1 and sim 1
            simerr1 = sqrt(sum((exp1 - sim1).^2,'omitnan')./(sum(~isnan(exp1-sim1))));
            
            % exp 1 and sim 2
            simerr2 = sqrt(sum((exp1 - sim2).^2,'omitnan')./(sum(~isnan(exp1-sim2))));
            
            % exp 2 and sim 1
            simerr3 = sqrt(sum((exp2 - sim1).^2,'omitnan')./(sum(~isnan(exp2-sim1))));
            
            % exp 2 and sim 2
            simerr4 = sqrt(sum((exp2 - sim2).^2,'omitnan')./(sum(~isnan(exp2-sim2))));
            
            app.RMSE_sim = 0.25*simerr1 + 0.25*simerr2 + 0.25*simerr3 + 0.25*simerr4;
            
            
            app.Q_sim = app.RMSE_sim./app.RMSE_exp;
        end
        
        % Velocity plots
        function PlotVelocityCFDVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataCFDVox(:,1)));
                if app.ThresholdVel == 1
                    alpha = zeros(size(app.ColourDataCFDVox(:,1)));
                    alpha(app.ColourDataCFDVox(:,1)>app.ThresholdVelmin & app.ColourDataCFDVox(:,1)<app.ThresholdVelmax) = app.VoxelsAlpha ;
                end
                vel_p = patch(app.UIAxesCFDVelocity,'Faces', app.CFDMappedF, 'Vertices', app.CFDMappedV,'FaceVertexCData', app.ColourDataCFDVox(:,1), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                vel_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesCFDVelocity); c.Label.String = 'velocity magnitude (m/s)';
                colormap(app.UIAxesCFDVelocity,jet);

                view(app.UIAxesCFDVelocity,3);
                set(app.UIAxesCFDVelocity,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesCFDVelocity.DataAspectRatio([1,1,1]);
                app.UIAxesCFDVelocity.CameraUpVector = [0 1 0];
                app.UIAxesCFDVelocity.Projection = 'orthographic';
                app.UIAxesCFDVelocity.CameraPosition = [-1,1,1];
                app.UIAxesCFDVelocity.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesCFDVelocity,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesCFDVelocity,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesCFDVelocity,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesCFDVelocity,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesCFDVelocity,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesCFDVelocity,'%.2f');
                %grid minor;
                caxis(app.UIAxesCFDVelocity,[app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesCFDVelocity,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                if isempty(app.PEPTCartesian) == 0
                    app.hlinkVelocity = linkprop([app.UIAxesCFDVelocity,app.UIAxesPEPTVelocity],...
                        {'CameraUpVector','CameraPosition','CameraViewAngle','CameraTarget'});      %{'Projection','DataAspectRatio'
                end
                app.Rotate = rotate3d(app.UIAxesCFDVelocity);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD velocity plot error','Icon','error');
                return
            end
        end
        function PlotVelocityPEPTVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataPEPTVox(:,1)));
                if app.ThresholdVel == 1
                    alpha = zeros(size(app.ColourDataPEPTVox(:,1)));
                    alpha(app.ColourDataPEPTVox(:,1)>app.ThresholdVelmin & app.ColourDataPEPTVox(:,1)<app.ThresholdVelmax) = app.VoxelsAlpha ;
                end

                vel_p = patch(app.UIAxesPEPTVelocity,'Faces', app.PEPTMappedF, 'Vertices', app.PEPTMappedV,'FaceVertexCData', app.ColourDataPEPTVox(:,1), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                vel_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesPEPTVelocity); c.Label.String = 'velocity magnitude (m/s)';
                colormap(app.UIAxesPEPTVelocity,jet);
                %             t = hgtransform('Parent',app.UIAxesPEPTVelocity);
                %             set(h,'Parent',t)
                %
                view(app.UIAxesPEPTVelocity,3);
                set(app.UIAxesPEPTVelocity,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesPEPTVelocity.DataAspectRatio([1,1,1]);
                app.UIAxesPEPTVelocity.CameraUpVector = [0 1 0];
                app.UIAxesPEPTVelocity.Projection = 'orthographic';
                app.UIAxesPEPTVelocity.CameraPosition = [-1,1,1];
                app.UIAxesPEPTVelocity.PlotBoxAspectRatio = [1 1 1];
                xlabel(app.UIAxesPEPTVelocity,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesPEPTVelocity,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesPEPTVelocity,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesPEPTVelocity,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesPEPTVelocity,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesPEPTVelocity,'%.2f');
                caxis(app.UIAxesPEPTVelocity,[app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesPEPTVelocity,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                if isempty(app.CFDCartesian) == 0
                    app.hlinkVelocity = linkprop([app.UIAxesCFDVelocity,app.UIAxesPEPTVelocity],...
                        {'CameraUpVector','CameraPosition','CameraViewAngle','CameraTarget'});      %{'Projection','DataAspectRatio'
                end

                app.Rotate = rotate3d(app.UIAxesPEPTVelocity);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT velocity plot error','Icon','error');
                return
            end
        end
        function ParityPlotVelocity(app)
            try
                % assign variables from .obj
                CFD_data = app.CFDVoxVelocity;
                PEPT_data = app.PEPTVoxVelocity;
                PEPT_err = app.StdErrPEPT;
                x45 = -100:1:100;
                y45 = x45;

                % remove zeros from data
                CFD_data(CFD_data==0) = NaN;
                PEPT_data(PEPT_data==0) = NaN;
                PEPT_err(PEPT_err==0) = NaN;

                % reshape 3D data into single column
                col_CFD = reshape(CFD_data,[],1);
                col_PEPT = reshape(PEPT_data,[],1);
                col_PEPTerr = reshape(PEPT_err,[],1);

                % Removing outliers from velocity data
                col_CFD(reshape(app.CFDVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                col_PEPT(reshape(app.PEPTVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                col_PEPTerr(reshape(app.PEPTVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;

                % fitting a linear model

                % Calculate the mean of Y
                mean_col_PEPT = mean(col_PEPT,'omitnan');

                % Calculate SSE (Sum of Squared Errors)
                SSE = sum((col_PEPT - col_CFD).^2,'omitnan');

                % Calculate SST (Total Sum of Squares)
                SST = sum((col_PEPT - mean_col_PEPT).^2,'omitnan');

                % Calculate R^2 statistic
                R2 = 1 - (SSE / SST);

                mdl = fitlm(col_CFD,col_PEPT);
                % R2 = mdl.Rsquared.Ordinary;

                % Reduced Chi-squared value
                chiSquared = sum(((col_PEPT-col_CFD).^2)./(col_PEPTerr.^2),'omitnan');
                reducedChiSquared = chiSquared./(sum(~isnan(col_PEPT-col_CFD))-2);
                

                if max(col_CFD) > max(col_PEPT)
                    xmax = max(col_CFD);
                    ymax = max(col_CFD);
                else
                    xmax = max(col_PEPT);
                    ymax = max(col_PEPT);
                end

                if min(col_CFD) < min(col_PEPT)
                    xmin = min(col_CFD);
                    ymin = min(col_CFD);
                else
                    xmin = min(col_PEPT);
                    ymin = min(col_PEPT);
                end

                xval = linspace(xmin,xmax,10);
                %reg = mdl.Coefficients{1,1}+xval.*mdl.Coefficients{2,1};
                CI = coefCI(mdl,0.05);
                ylow = CI(1,1) + CI(2,1)*xval;
                yupp = CI(1,2) + CI(2,2)*xval;
                app.parity_velocity = [col_CFD, col_PEPT]; %variable for exporting parity data

                % plot
                plot(app.UIAxesParityVelocity,col_CFD,col_PEPT,'.k');
                %errorbar(app.UIAxesParityVelocity,col_CFD,col_PEPT,col_PEPTerr,'.k','CapSize',1)
                hold(app.UIAxesParityVelocity,'on');
                %                 plot(app.UIAxesParityVelocity,xval,reg,'-k',x45,y45,'-r');
                plot(app.UIAxesParityVelocity,x45,y45,'-r');
                pt = patch(app.UIAxesParityVelocity,[xval fliplr(xval)], [ylow fliplr(yupp)], 'k','EdgeColor','none');
                hold(app.UIAxesParityVelocity,'off');
                pt.FaceAlpha = 0.15;
                str1 = string(strcat('\ $r^2 = $',{' '},num2str(R2,'%.2g'),'\ $Q_{sim} = $',{' '},num2str(app.Q_sim,'%.3g')));

                % str1 = string(strcat('\ $r^2 = $',{' '},num2str(R2,'%.2g'),'\ $\chi^2 = $',{' '},num2str(reducedChiSquared,'%.2g')));
                %annotation(app.UIAxesParityVelocity,'textbox',[0.15,0.7,0.2,0.2],'String',{str1},'FitBoxToText','on','BackgroundColor','w','fontsize',16,'Interpreter','latex');
                % annotations dont work with UIAxes... find a workaround...
                % Graph properties
                set(app.UIAxesParityVelocity,'fontsize',14,'TickLabelInterpreter','latex');
                title(app.UIAxesParityVelocity,str1,'fontsize',24,'Interpreter','latex');
                app.UIAxesParityVelocity.PlotBoxAspectRatio = [1 1 1];


                %grid minor;
                axis(app.UIAxesParityVelocity,[app.parityMinVelocity app.parityMaxVelocity app.parityMinVelocity app.parityMaxVelocity]); %0.25 is tipspeed
                xlabel(app.UIAxesParityVelocity,'CFD velocity (m/s)','fontsize',12);
                ylabel(app.UIAxesParityVelocity,'PEPT velocity (m/s)','fontsize',12);
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'Velocity parity plot error','Icon','error');
                return
            end
        end
        function PlotVelocityCFDSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxVelocityNaN,[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxVelocityNaN,[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxVelocityNaN,[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD velocity tomography error','Icon','error');
                return
            end
        end

        function PlotVelocityPEPTSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.PEPTgif_order_z)

                    while app.PEPTgif_order_z(i)==app.PEPTgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxVelocityNaN,[2 1 3]),[],[],app.PEPTgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.PEPTgif_order_y)

                    while app.PEPTgif_order_y(i)==app.PEPTgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxVelocityNaN,[2 1 3]),[],app.PEPTgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.PEPTgif_order_x)

                    while app.PEPTgif_order_x(i)==app.PEPTgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxVelocityNaN,[2 1 3]),app.PEPTgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT velocity tomography error','Icon','error');
                return
            end
        end

        % Comparison of velocities
        function CompareVelocitySlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'delta velocity magnitude (m/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(abs(app.CFDVoxVelocityNaN-app.PEPTVoxVelocityNaN),[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'delta velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(abs(app.CFDVoxVelocityNaN-app.PEPTVoxVelocityNaN),[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'delta velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(abs(app.CFDVoxVelocityNaN-app.PEPTVoxVelocityNaN),[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'delta velocity magnitude (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.UMin app.UMax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'velocity tomography error','Icon','error');
                return
            end
        end


        % N Passes plots
        function PlotNPassesCFDVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataCFDVox(:,2)));
                if app.ThresholdNpass == 1
                    alpha = zeros(size(app.ColourDataCFDVox(:,2)));
                    alpha(app.ColourDataCFDVox(:,2)>app.ThresholdNpassmin & app.ColourDataCFDVox(:,2)<app.ThresholdNpassmax) = app.VoxelsAlpha ;
                end

                NPass_p = patch(app.UIAxesCFDNPasses,'Faces', app.CFDMappedF, 'Vertices', app.CFDMappedV,'FaceVertexCData', app.ColourDataCFDVox(:,2), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                NPass_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesCFDNPasses); c.Label.String = 'Number of Passes';
                colormap(app.UIAxesCFDNPasses,jet);

                view(app.UIAxesCFDNPasses,3);
                set(app.UIAxesCFDNPasses,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesCFDNPasses.DataAspectRatio([1,1,1]);
                app.UIAxesCFDNPasses.CameraUpVector = [0 1 0];
                app.UIAxesCFDNPasses.Projection = 'orthographic';
                app.UIAxesCFDNPasses.CameraPosition = [-1,1,1];
                app.UIAxesCFDNPasses.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesCFDNPasses,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesCFDNPasses,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesCFDNPasses,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesCFDNPasses,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesCFDNPasses,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesCFDNPasses,'%.2f');
                %grid minor;
                caxis(app.UIAxesCFDNPasses,[app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesCFDNPasses,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.PEPTCartesian) == 0
                    app.hlinkNPasses = linkprop([app.UIAxesCFDNPasses,app.UIAxesPEPTNPasses],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end
                app.Rotate = rotate3d(app.UIAxesCFDNPasses);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD nPass plot error','Icon','error');
                return
            end
        end
        function PlotNPassesPEPTVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataPEPTVox(:,2)));
                if app.ThresholdNpass == 1
                    alpha = zeros(size(app.ColourDataPEPTVox(:,2)));
                    alpha(app.ColourDataPEPTVox(:,2)>app.ThresholdNpassmin & app.ColourDataPEPTVox(:,2)<app.ThresholdNpassmax) = app.VoxelsAlpha ;
                end
                NPass_p = patch(app.UIAxesPEPTNPasses,'Faces', app.PEPTMappedF, 'Vertices', app.PEPTMappedV,'FaceVertexCData', app.ColourDataPEPTVox(:,2), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                NPass_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesPEPTNPasses); c.Label.String = 'Number of Passes';
                colormap(app.UIAxesPEPTNPasses,jet);

                view(app.UIAxesPEPTNPasses,3);
                set(app.UIAxesPEPTNPasses,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesPEPTNPasses.DataAspectRatio([1,1,1]);
                app.UIAxesPEPTNPasses.CameraUpVector = [0 1 0];
                app.UIAxesPEPTNPasses.Projection = 'orthographic';
                app.UIAxesPEPTNPasses.CameraPosition = [-1,1,1];
                app.UIAxesPEPTNPasses.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesPEPTNPasses,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesPEPTNPasses,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesPEPTNPasses,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesPEPTNPasses,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesPEPTNPasses,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesPEPTNPasses,'%.2f');
                %grid minor;
                caxis(app.UIAxesPEPTNPasses,[app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesPEPTNPasses,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.CFDCartesian) == 0
                    app.hlinkNPasses = linkprop([app.UIAxesCFDNPasses,app.UIAxesPEPTNPasses],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end
                app.Rotate = rotate3d(app.UIAxesPEPTNPasses);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT nPass plot error','Icon','error');
                return
            end
        end
        function PlotNPassesCFDSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Number of Passes';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxNPassesNaN,[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Number of Passes';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxNPassesNaN,[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Number of Passes';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxNPassesNaN,[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Number of Passes';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD nPass tomography error','Icon','error');
                return
            end
        end
        function PlotNPassesPEPTSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Number of Passes';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.PEPTgif_order_z)

                    while app.PEPTgif_order_z(i)==app.PEPTgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxNPassesNaN,[2 1 3]),[],[],app.PEPTgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Number of Passes';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.PEPTgif_order_y)

                    while app.PEPTgif_order_y(i)==app.PEPTgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxNPassesNaN,[2 1 3]),[],app.PEPTgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Number of Passes';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.PEPTgif_order_x)

                    while app.PEPTgif_order_x(i)==app.PEPTgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxNPassesNaN,[2 1 3]),app.PEPTgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Number of Passes';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Npassmin app.Npassmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT nPass tomography error','Icon','error');
                return
            end
        end


        % Occupancy plots
        function PlotOccupancyCFDVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataCFDVox(:,3)));
                if app.ThresholdOcc == 1
                    alpha = zeros(size(app.ColourDataCFDVox(:,3)));
                    alpha(app.ColourDataCFDVox(:,3)>app.ThresholdOccmin & app.ColourDataCFDVox(:,3)<app.ThresholdOccmax) = app.VoxelsAlpha ;
                end

                occ_p = patch(app.UIAxesCFDOccupancy,'Faces', app.CFDMappedF, 'Vertices', app.CFDMappedV,'FaceVertexCData', app.ColourDataCFDVox(:,3), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                occ_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesCFDOccupancy); c.Label.String = 'Occupancy s-1';
                colormap(app.UIAxesCFDOccupancy,jet);

                view(app.UIAxesCFDOccupancy,3);
                set(app.UIAxesCFDOccupancy,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                if app.LogScaleColorbar ==1
                    set(app.UIAxesCFDOccupancy,'ColorScale','log');
                else
                    set(app.UIAxesCFDOccupancy,'ColorScale','linear');
                end
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesCFDOccupancy.DataAspectRatio([1,1,1]);
                app.UIAxesCFDOccupancy.CameraUpVector = [0 1 0];
                app.UIAxesCFDOccupancy.Projection = 'orthographic';
                app.UIAxesCFDOccupancy.CameraPosition = [-1,1,1];
                app.UIAxesCFDOccupancy.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesCFDOccupancy,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesCFDOccupancy,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesCFDOccupancy,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesCFDOccupancy,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesCFDOccupancy,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesCFDOccupancy,'%.2f');
                %grid minor;
                caxis(app.UIAxesCFDOccupancy,[app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesCFDOccupancy,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.PEPTCartesian) == 0
                    app.hlinkOccupancy = linkprop([app.UIAxesCFDOccupancy,app.UIAxesPEPTOccupancy],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end

                app.Rotate = rotate3d(app.UIAxesCFDOccupancy);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD occupancy plot error','Icon','error');
                return
            end
        end
        function PlotOccupancyPEPTVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataPEPTVox(:,3)));
                if app.ThresholdOcc == 1
                    alpha = zeros(size(app.ColourDataPEPTVox(:,3)));
                    alpha(app.ColourDataPEPTVox(:,3)>app.ThresholdOccmin & app.ColourDataPEPTVox(:,3)<app.ThresholdOccmax) = app.VoxelsAlpha ;
                end

                occ_p = patch(app.UIAxesPEPTOccupancy,'Faces', app.PEPTMappedF, 'Vertices', app.PEPTMappedV,'FaceVertexCData', app.ColourDataPEPTVox(:,3), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                occ_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesPEPTOccupancy); c.Label.String = 'Occupancy s-1';
                colormap(app.UIAxesPEPTOccupancy,jet);

                view(app.UIAxesPEPTOccupancy,3);
                set(app.UIAxesPEPTOccupancy,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                if app.LogScaleColorbar ==1
                    set(app.UIAxesCFDOccupancy,'ColorScale','log');
                else
                    set(app.UIAxesCFDOccupancy,'ColorScale','linear');
                end
                app.UIAxesPEPTOccupancy.DataAspectRatio([1,1,1]);
                app.UIAxesPEPTOccupancy.CameraUpVector = [0 1 0];
                app.UIAxesPEPTOccupancy.Projection = 'orthographic';
                app.UIAxesPEPTOccupancy.CameraPosition = [-1,1,1];
                app.UIAxesPEPTOccupancy.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesPEPTOccupancy,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesPEPTOccupancy,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesPEPTOccupancy,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesPEPTOccupancy,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesPEPTOccupancy,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesPEPTOccupancy,'%.2f');
                %grid minor;
                caxis(app.UIAxesPEPTOccupancy,[app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesPEPTOccupancy,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                if isempty(app.CFDCartesian) == 0
                    app.hlinkOccupancy = linkprop([app.UIAxesCFDOccupancy,app.UIAxesPEPTOccupancy],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end
                app.Rotate = rotate3d(app.UIAxesPEPTOccupancy);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT occupancy plot error','Icon','error');
                return
            end
        end
        function ParityPlotOccupancy(app)
            try
                % assign variables from .obj
                CFD_data = app.CFDVoxOccupancy;
                PEPT_data = app.PEPTVoxOccupancy;
                x45 = -100:1:100;
                y45 = x45;
                % remove zeros from data
                CFD_data(CFD_data==0) = NaN;
                PEPT_data(PEPT_data==0) = NaN;

                % reshape 3D data into single column
                col_CFD = reshape(CFD_data,[],1);
                col_PEPT = reshape(PEPT_data,[],1);
                col_CFD(reshape(app.CFDVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                col_PEPT(reshape(app.PEPTVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;

                % fitting a linear model

                % Calculate the mean of Y
                mean_col_PEPT = mean(col_PEPT,'omitnan');

                % Calculate SSE (Sum of Squared Errors)
                SSE = sum((col_PEPT - col_CFD).^2,'omitnan');

                % Calculate SST (Total Sum of Squares)
                SST = sum((col_PEPT - mean_col_PEPT).^2,'omitnan');

                % Calculate R^2 statistic
                R2 = 1 - (SSE / SST);

                mdl = fitlm(col_CFD,col_PEPT);

                if max(col_CFD) > max(col_PEPT)
                    xmax = max(col_CFD);
                    ymax = max(col_CFD);
                else
                    xmax = max(col_PEPT);
                    ymax = max(col_PEPT);
                end

                if min(col_CFD) < min(col_PEPT)
                    xmin = min(col_CFD);
                    ymin = min(col_CFD);
                else
                    xmin = min(col_PEPT);
                    ymin = min(col_PEPT);
                end

                app.parity_occupancy = [col_CFD, col_PEPT];
                xval = linspace(xmin,xmax,10);
                reg = mdl.Coefficients{1,1}+xval.*mdl.Coefficients{2,1};
                CI = coefCI(mdl,0.05);
                ylow = CI(1,1) + CI(2,1)*xval;
                yupp = CI(1,2) + CI(2,2)*xval;


                % plot
                plot(app.UIAxesParityOccupancy,col_CFD,col_PEPT,'.k',xval,reg,'-k',x45,y45,'-r');
                hold(app.UIAxesParityOccupancy,'on');
                pt = patch(app.UIAxesParityOccupancy,[xval fliplr(xval)], [ylow fliplr(yupp)], 'k','EdgeColor','none');
                hold(app.UIAxesParityOccupancy,'off');
                pt.FaceAlpha = 0.15;
                str1 = string(strcat('\ $r^2 = $',{' '},num2str(R2,'%.2g')));
                %annotation(app.UIAxesParityVelocity,'textbox',[0.15,0.7,0.2,0.2],'String',{str1},'FitBoxToText','on','BackgroundColor','w','fontsize',16,'Interpreter','latex');
                % annotations dont work with UIAxes... find a workaround...
                % Graph properties
                set(app.UIAxesParityOccupancy,'fontsize',14,'TickLabelInterpreter','latex');
                title(app.UIAxesParityOccupancy,str1,'fontsize',32,'Interpreter','latex');
                app.UIAxesParityOccupancy.PlotBoxAspectRatio = [1 1 1];


                %grid minor;
                axis(app.UIAxesParityOccupancy,[app.parityMinOccupancy app.parityMaxOccupancy app.parityMinOccupancy app.parityMaxOccupancy]); %0.25 is tipspeed
                xlabel(app.UIAxesParityOccupancy,'CFD Occupancy (s-1)','fontsize',12);
                ylabel(app.UIAxesParityOccupancy,'PEPT Occupancy (s-1)','fontsize',12);
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'Occupancy parity plot error','Icon','error');
                return
            end

        end
        function PlotOccupancyCFDSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Occupancy s-1';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                if app.LogScaleColorbar ==1
                    set(gca,'ColorScale','log');
                else
                    set(gca,'ColorScale','linear');
                end
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxOccupancyNaN,[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Occupancy s-1';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxOccupancyNaN,[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Occupancy s-1';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxOccupancyNaN,[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Occupancy s-1';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD occupancy tomography error','Icon','error');
                return
            end
        end
        function PlotOccupancyPEPTSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Occupancy s-1';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                if app.LogScaleColorbar ==1
                    set(gca,'ColorScale','log');
                else
                    set(gca,'ColorScale','linear');
                end
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.PEPTgif_order_z)

                    while app.PEPTgif_order_z(i)==app.PEPTgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxOccupancyNaN,[2 1 3]),[],[],app.PEPTgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Occupancy s-1';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.PEPTgif_order_y)

                    while app.PEPTgif_order_y(i)==app.PEPTgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxOccupancyNaN,[2 1 3]),[],app.PEPTgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Occupancy s-1';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.PEPTgif_order_x)

                    while app.PEPTgif_order_x(i)==app.PEPTgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxOccupancyNaN,[2 1 3]),app.PEPTgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Occupancy s-1';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex','ColorScale','log');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Occmin app.Occmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT occupancy tomography error','Icon','error');
                return
            end
        end


        % Dispersion plots
        function PlotDispersionCFDVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataCFDVox(:,4)));
                if app.ThresholdDispersion == 1
                    alpha = zeros(size(app.ColourDataCFDVox(:,4)));
                    alpha(app.ColourDataCFDVox(:,4)>app.ThresholdDispersionmin & app.ColourDataCFDVox(:,4)<app.ThresholdDispersionmax) = app.VoxelsAlpha ;
                end

                dispersion_p = patch(app.UIAxesCFDDispersion,'Faces', app.CFDMappedF, 'Vertices', app.CFDMappedV,'FaceVertexCData', app.ColourDataCFDVox(:,4), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                dispersion_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesCFDDispersion); c.Label.String = 'Rate of Dispersion (m2/s)';
                colormap(app.UIAxesCFDDispersion,jet);

                view(app.UIAxesCFDDispersion,3);
                set(app.UIAxesCFDDispersion,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesCFDDispersion.DataAspectRatio([1,1,1]);
                app.UIAxesCFDDispersion.CameraUpVector = [0 1 0];
                app.UIAxesCFDDispersion.Projection = 'orthographic';
                app.UIAxesCFDDispersion.CameraPosition = [-1,1,1];
                app.UIAxesCFDDispersion.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesCFDDispersion,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesCFDDispersion,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesCFDDispersion,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesCFDDispersion,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesCFDDispersion,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesCFDDispersion,'%.2f');
                %grid minor;
                caxis(app.UIAxesCFDDispersion,[app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesCFDDispersion,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.PEPTCartesian) == 0
                    app.hlinkDispersion = linkprop([app.UIAxesCFDDispersion,app.UIAxesPEPTDispersion],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end

                app.Rotate = rotate3d(app.UIAxesCFDDispersion);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD dispersion plot error','Icon','error');
                return
            end
        end
        function PlotDispersionPEPTVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataPEPTVox(:,4)));
                if app.ThresholdDispersion == 1
                    alpha = zeros(size(app.ColourDataPEPTVox(:,4)));
                    alpha(app.ColourDataPEPTVox(:,4)>app.ThresholdDispersionmin & app.ColourDataPEPTVox(:,4)<app.ThresholdDispersionmax) = app.VoxelsAlpha ;
                end

                dispersion_p = patch(app.UIAxesPEPTDispersion,'Faces', app.PEPTMappedF, 'Vertices', app.PEPTMappedV,'FaceVertexCData', app.ColourDataPEPTVox(:,4), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                dispersion_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesPEPTDispersion); c.Label.String = 'Rate of Dispersion (m2/s)';
                colormap(app.UIAxesPEPTDispersion,jet);

                view(app.UIAxesPEPTDispersion,3);
                set(app.UIAxesPEPTDispersion,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesPEPTDispersion.DataAspectRatio([1,1,1]);
                app.UIAxesPEPTDispersion.CameraUpVector = [0 1 0];
                app.UIAxesPEPTDispersion.Projection = 'orthographic';
                app.UIAxesPEPTDispersion.CameraPosition = [-1,1,1];
                app.UIAxesPEPTDispersion.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesPEPTDispersion,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesPEPTDispersion,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesPEPTDispersion,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesPEPTDispersion,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesPEPTDispersion,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesPEPTDispersion,'%.2f');
                %grid minor;
                caxis(app.UIAxesPEPTDispersion,[app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesPEPTDispersion,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.CFDCartesian) == 0
                    app.hlinkDispersion = linkprop([app.UIAxesCFDDispersion,app.UIAxesPEPTDispersion],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end

                app.Rotate = rotate3d(app.UIAxesPEPTDispersion);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT dispersion plot error','Icon','error');
                return
            end
        end
        function ParityPlotDispersion(app)
            try
                % assign variables from .obj
                CFD_data = app.CFDVoxDispersionRate;
                PEPT_data = app.PEPTVoxDispersionRate;
                x45 = -100:1:100;
                y45 = x45;
                % remove zeros from data
                CFD_data(CFD_data==0) = NaN;
                PEPT_data(PEPT_data==0) = NaN;

                % reshape 3D data into single column
                col_CFD = reshape(CFD_data,[],1);
                col_PEPT = reshape(PEPT_data,[],1);

                col_CFD(reshape(app.CFDVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                col_PEPT(reshape(app.PEPTVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;

                % fitting a linear model
                mdl = fitlm(col_CFD,col_PEPT);
                R2 = mdl.Rsquared.Ordinary;

                if max(col_CFD) > max(col_PEPT)
                    xmax = max(col_CFD);
                    ymax = max(col_CFD);
                else
                    xmax = max(col_PEPT);
                    ymax = max(col_PEPT);
                end

                if min(col_CFD) < min(col_PEPT)
                    xmin = min(col_CFD);
                    ymin = min(col_CFD);
                else
                    xmin = min(col_PEPT);
                    ymin = min(col_PEPT);
                end

                xval = linspace(xmin,xmax,10);
                reg = mdl.Coefficients{1,1}+xval.*mdl.Coefficients{2,1};
                CI = coefCI(mdl,0.05);
                ylow = CI(1,1) + CI(2,1)*xval;
                yupp = CI(1,2) + CI(2,2)*xval;


                % plot
                plot(app.UIAxesParityDispersion,col_CFD,col_PEPT,'.k',xval,reg,'-k',x45,y45,'-r');
                hold(app.UIAxesParityDispersion,'on');
                pt = patch(app.UIAxesParityDispersion,[xval fliplr(xval)], [ylow fliplr(yupp)], 'k','EdgeColor','none');
                hold(app.UIAxesParityDispersion,'off');
                pt.FaceAlpha = 0.15;
                str1 = string(strcat('\ $r^2 = $',{' '},num2str(R2,'%.2g')));
                %annotation(app.UIAxesParityVelocity,'textbox',[0.15,0.7,0.2,0.2],'String',{str1},'FitBoxToText','on','BackgroundColor','w','fontsize',16,'Interpreter','latex');
                % annotations dont work with UIAxes... find a workaround...
                % Graph properties
                set(app.UIAxesParityDispersion,'fontsize',14,'TickLabelInterpreter','latex');
                title(app.UIAxesParityDispersion,str1,'fontsize',32,'Interpreter','latex');
                app.UIAxesParityDispersion.PlotBoxAspectRatio = [1 1 1];


                %grid minor;
                axis(app.UIAxesParityDispersion,[app.parityMinDispersion app.parityMaxDispersion app.parityMinDispersion app.parityMaxDispersion]); %0.25 is tipspeed
                xlabel(app.UIAxesParityDispersion,'CFD Dispersion Rate (m2/s)','fontsize',12);
                ylabel(app.UIAxesParityDispersion,'PEPT Dispersion Rate (m2/s)','fontsize',12);
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'Dispersion parity plot error','Icon','error');
                return
            end
        end
        function PlotDispersionCFDSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxDispersionRateNaN,[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxDispersionRateNaN,[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxDispersionRateNaN,[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD dispersion tomography error','Icon','error');
                return
            end
        end
        function PlotDispersionPEPTSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.PEPTgif_order_z)

                    while app.PEPTgif_order_z(i)==app.PEPTgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxDispersionRateNaN,[2 1 3]),[],[],app.PEPTgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.PEPTgif_order_y)

                    while app.PEPTgif_order_y(i)==app.PEPTgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxDispersionRateNaN,[2 1 3]),[],app.PEPTgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.PEPTgif_order_x)

                    while app.PEPTgif_order_x(i)==app.PEPTgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxDispersionRateNaN,[2 1 3]),app.PEPTgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Dispersion (m2/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Dispersionmin app.Dispersionmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT dispersion tomography error','Icon','error');
                return
            end
        end


        % Displacement plots
        function PlotDisplacementCFDVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataCFDVox(:,5)));
                if app.ThresholdDisplacement == 1
                    alpha = zeros(size(app.ColourDataCFDVox(:,5)));
                    alpha(app.ColourDataCFDVox(:,5)>app.ThresholdDisplacementmin & app.ColourDataCFDVox(:,5)<app.ThresholdDisplacementmax) = app.VoxelsAlpha ;
                end

                displacement_p = patch(app.UIAxesCFDDisplacement,'Faces', app.CFDMappedF, 'Vertices', app.CFDMappedV,'FaceVertexCData', app.ColourDataCFDVox(:,5), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                displacement_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesCFDDisplacement); c.Label.String = 'Rate of Displacement (m/s)';
                colormap(app.UIAxesCFDDisplacement,jet);

                view(app.UIAxesCFDDisplacement,3);
                set(app.UIAxesCFDDisplacement,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesCFDDisplacement.DataAspectRatio([1,1,1]);
                app.UIAxesCFDDisplacement.CameraUpVector = [0 1 0];
                app.UIAxesCFDDisplacement.Projection = 'orthographic';
                app.UIAxesCFDDisplacement.CameraPosition = [-1,1,1];
                app.UIAxesCFDDisplacement.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesCFDDisplacement,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesCFDDisplacement,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesCFDDisplacement,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesCFDDisplacement,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesCFDDisplacement,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesCFDDisplacement,'%.2f');
                %grid minor;
                caxis(app.UIAxesCFDDisplacement,[app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesCFDDisplacement,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.PEPTCartesian) == 0
                    app.hlinkDisplacement = linkprop([app.UIAxesCFDDisplacement,app.UIAxesPEPTDisplacement],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end

                app.Rotate = rotate3d(app.UIAxesCFDDisplacement);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD displacement plot error','Icon','error');
                return
            end
        end
        function PlotDisplacementPEPTVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataPEPTVox(:,5)));
                if app.ThresholdDisplacement == 1
                    alpha = zeros(size(app.ColourDataPEPTVox(:,5)));
                    alpha(app.ColourDataPEPTVox(:,5)>app.ThresholdDisplacementmin & app.ColourDataPEPTVox(:,5)<app.ThresholdDisplacementmax) = app.VoxelsAlpha ;
                end


                displacement_p = patch(app.UIAxesPEPTDisplacement,'Faces', app.PEPTMappedF, 'Vertices', app.PEPTMappedV,'FaceVertexCData', app.ColourDataPEPTVox(:,5), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                displacement_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesPEPTDisplacement); c.Label.String = 'Rate of Displacement (m/s)';
                colormap(app.UIAxesPEPTDisplacement,jet);

                view(app.UIAxesPEPTDisplacement,3);
                set(app.UIAxesPEPTDisplacement,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesPEPTDisplacement.DataAspectRatio([1,1,1]);
                app.UIAxesPEPTDisplacement.CameraUpVector = [0 1 0];
                app.UIAxesPEPTDisplacement.Projection = 'orthographic';
                app.UIAxesPEPTDisplacement.CameraPosition = [-1,1,1];
                app.UIAxesPEPTDisplacement.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesPEPTDisplacement,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesPEPTDisplacement,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesPEPTDisplacement,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesPEPTDisplacement,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesPEPTDisplacement,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesPEPTDisplacement,'%.2f');
                %grid minor;
                caxis(app.UIAxesPEPTDisplacement,[app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesPEPTDisplacement,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.CFDCartesian) == 0
                    app.hlinkDisplacement = linkprop([app.UIAxesCFDDisplacement,app.UIAxesPEPTDisplacement],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end
                app.Rotate = rotate3d(app.UIAxesPEPTDisplacement);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT displacement plot error','Icon','error');
                return
            end
        end
        function ParityPlotDisplacement(app)
            try
                % assign variables from .obj
                CFD_data = app.CFDVoxDisplacementRate;
                PEPT_data = app.PEPTVoxDisplacementRate;
                x45 = -100:1:100;
                y45 = x45;
                % remove zeros from data
                CFD_data(CFD_data==0) = NaN;
                PEPT_data(PEPT_data==0) = NaN;

                % reshape 3D data into single column
                col_CFD = reshape(CFD_data,[],1);
                col_PEPT = reshape(PEPT_data,[],1);

                % Removing outliers from velocity data
                col_CFD(reshape(app.CFDVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                col_PEPT(reshape(app.PEPTVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                %             [~,Parity_outliers] = rmoutliers(col_PEPT./col_CFD,'median','ThresholdFactor',5);
                %             col_CFD(Parity_outliers) = NaN;
                %             col_PEPT(Parity_outliers) = NaN;
                %
                %             [~,Parity_outliers] = rmoutliers(col_CFD./col_PEPT,'median','ThresholdFactor',5);
                %             col_CFD(Parity_outliers) = NaN;
                %             col_PEPT(Parity_outliers) = NaN;

                % fitting a linear model
                mdl = fitlm(col_CFD,col_PEPT);
                R2 = mdl.Rsquared.Ordinary;

                if max(col_CFD) > max(col_PEPT)
                    xmax = max(col_CFD);
                    ymax = max(col_CFD);
                else
                    xmax = max(col_PEPT);
                    ymax = max(col_PEPT);
                end

                if min(col_CFD) < min(col_PEPT)
                    xmin = min(col_CFD);
                    ymin = min(col_CFD);
                else
                    xmin = min(col_PEPT);
                    ymin = min(col_PEPT);
                end

                xval = linspace(xmin,xmax,10);
                reg = mdl.Coefficients{1,1}+xval.*mdl.Coefficients{2,1};
                CI = coefCI(mdl,0.05);
                ylow = CI(1,1) + CI(2,1)*xval;
                yupp = CI(1,2) + CI(2,2)*xval;


                % plot
                plot(app.UIAxesParityDisplacement,col_CFD,col_PEPT,'.k',xval,reg,'-k',x45,y45,'-r');
                hold(app.UIAxesParityDisplacement,'on');
                pt = patch(app.UIAxesParityDisplacement,[xval fliplr(xval)], [ylow fliplr(yupp)], 'k','EdgeColor','none');
                hold(app.UIAxesParityDisplacement,'off');
                pt.FaceAlpha = 0.15;
                str1 = string(strcat('\ $r^2 = $',{' '},num2str(R2,'%.2g')));
                %annotation(app.UIAxesParityVelocity,'textbox',[0.15,0.7,0.2,0.2],'String',{str1},'FitBoxToText','on','BackgroundColor','w','fontsize',16,'Interpreter','latex');
                % annotations dont work with UIAxes... find a workaround...
                % Graph properties
                set(app.UIAxesParityDisplacement,'fontsize',14,'TickLabelInterpreter','latex');
                title(app.UIAxesParityDisplacement,str1,'fontsize',32,'Interpreter','latex');
                app.UIAxesParityDisplacement.PlotBoxAspectRatio = [1 1 1];


                %grid minor;
                axis(app.UIAxesParityDisplacement,[app.parityMinDisplacement app.parityMaxDisplacement app.parityMinDisplacement app.parityMaxDisplacement]); %0.25 is tipspeed
                xlabel(app.UIAxesParityDisplacement,'CFD Displacement Rate (m/s)','fontsize',12);
                ylabel(app.UIAxesParityDisplacement,'PEPT Displacement Rate (m/s)','fontsize',12);
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'Displacement parity plot error','Icon','error');
                return
            end
        end

        function PlotDisplacementCFDSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxDisplacementRateNaN,[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxDisplacementRateNaN,[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxDisplacementRateNaN,[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD displacement tomography error','Icon','error');
                return
            end
        end
        function PlotDisplacementPEPTSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.PEPTgif_order_z)

                    while app.PEPTgif_order_z(i)==app.PEPTgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxDisplacementRateNaN,[2 1 3]),[],[],app.PEPTgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.PEPTgif_order_y)

                    while app.PEPTgif_order_y(i)==app.PEPTgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxDisplacementRateNaN,[2 1 3]),[],app.PEPTgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.PEPTgif_order_x)

                    while app.PEPTgif_order_x(i)==app.PEPTgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxDisplacementRateNaN,[2 1 3]),app.PEPTgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Rate of Displacement (m/s)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Displacementmin app.Displacementmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT displacement tomography error','Icon','error');
                return
            end
        end


        % Normalised Mixer effectiveness plots
        function PlotMixEffCFDVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataCFDVox(:,6)));
                if app.ThresholdMixEff == 1
                    alpha = zeros(size(app.ColourDataCFDVox(:,6)));
                    alpha(app.ColourDataCFDVox(:,6)>app.ThresholdMixEffmin & app.ColourDataCFDVox(:,6)<app.ThresholdMixEffmax) = app.VoxelsAlpha ;
                end

                mixeff_p = patch(app.UIAxesCFDMixEff,'Faces', app.CFDMappedF, 'Vertices', app.CFDMappedV,'FaceVertexCData', app.ColourDataCFDVox(:,7), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                mixeff_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesCFDMixEff); c.Label.String = 'Normalised ME (-)';
                colormap(app.UIAxesCFDMixEff,jet);

                view(app.UIAxesCFDMixEff,3);
                set(app.UIAxesCFDMixEff,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesCFDMixEff.DataAspectRatio([1,1,1]);
                app.UIAxesCFDMixEff.CameraUpVector = [0 1 0];
                app.UIAxesCFDMixEff.Projection = 'orthographic';
                app.UIAxesCFDMixEff.CameraPosition = [-1,1,1];
                app.UIAxesCFDMixEff.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesCFDMixEff,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesCFDMixEff,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesCFDMixEff,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesCFDMixEff,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesCFDMixEff,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesCFDMixEff,'%.2f');
                %grid minor;
                caxis(app.UIAxesCFDMixEff,[app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesCFDMixEff,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.PEPTCartesian) == 0
                    app.hlinkMixEff = linkprop([app.UIAxesCFDMixEff,app.UIAxesPEPTMixEff],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end

                app.Rotate = rotate3d(app.UIAxesCFDMixEff);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD mix-eff plot error','Icon','error');
                return
            end
        end
        function PlotMixEffPEPTVox(app)
            try
                alpha = repmat(app.VoxelsAlpha,size(app.ColourDataPEPTVox(:,6)));
                if app.ThresholdMixEff == 1
                    alpha = zeros(size(app.ColourDataPEPTVox(:,6)));
                    alpha(app.ColourDataPEPTVox(:,6)>app.ThresholdMixEffmin & app.ColourDataPEPTVox(:,6)<app.ThresholdMixEffmax) = app.VoxelsAlpha ;
                end

                mixeff_p = patch(app.UIAxesPEPTMixEff,'Faces', app.PEPTMappedF, 'Vertices', app.PEPTMappedV,'FaceVertexCData', app.ColourDataPEPTVox(:,7), ...
                    'LineWidth', 0.2, 'EdgeColor', 'none', 'FaceAlpha', 'flat','FaceVertexAlphaData',alpha,'AlphaDataMapping','none'); % creates faces coloured by data in colidx
                mixeff_p.FaceColor = 'flat';
                c = colorbar(app.UIAxesPEPTMixEff); c.Label.String = 'Normalised ME (-)';
                colormap(app.UIAxesPEPTMixEff,jet);

                view(app.UIAxesPEPTMixEff,3);
                set(app.UIAxesPEPTMixEff,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                %             set(gcf,'color','w','position',[100,100,600,500]);
                app.UIAxesPEPTMixEff.DataAspectRatio([1,1,1]);
                app.UIAxesPEPTMixEff.CameraUpVector = [0 1 0];
                app.UIAxesPEPTMixEff.Projection = 'orthographic';
                app.UIAxesPEPTMixEff.CameraPosition = [-1,1,1];
                app.UIAxesPEPTMixEff.PlotBoxAspectRatio = [1 1 1];

                xlabel(app.UIAxesPEPTMixEff,'x [m]','FontSize',10,'FontWeight','b');
                ylabel(app.UIAxesPEPTMixEff,'y [m]','FontSize',10,'FontWeight','b');
                zlabel(app.UIAxesPEPTMixEff,'z [m]','FontSize',10,'FontWeight','b');
                xtickformat(app.UIAxesPEPTMixEff,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ytickformat(app.UIAxesPEPTMixEff,'%.2f'); %specifies tick value's number of dp (2 = 2 dp)
                ztickformat(app.UIAxesPEPTMixEff,'%.2f');
                %grid minor;
                caxis(app.UIAxesPEPTMixEff,[app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                axis(app.UIAxesPEPTMixEff,[-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);

                if isempty(app.CFDCartesian) == 0
                    app.hlinkMixEff = linkprop([app.UIAxesCFDMixEff,app.UIAxesPEPTMixEff],...
                        {'DataAspectRatio'
                        'CameraUpVector'
                        'Projection'
                        'CameraPosition' ...
                        });
                end
                app.Rotate = rotate3d(app.UIAxesPEPTMixEff);
                app.Rotate.Enable = 'on';
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT mix-eff plot error','Icon','error');
                return
            end
        end
        function ParityPlotMixEffNorm(app)
            try
                % assign variables from .obj
                CFD_data = app.CFDVoxMixEffNorm;
                PEPT_data = app.PEPTVoxMixEffNorm;
                x45 = -100:1:100;
                y45 = x45;
                % remove zeros from data
                CFD_data(CFD_data==0) = NaN;
                PEPT_data(PEPT_data==0) = NaN;

                % reshape 3D data into single column
                col_CFD = reshape(CFD_data,[],1);
                col_PEPT = reshape(PEPT_data,[],1);

                % Removing outliers from velocity data
                col_CFD(reshape(app.CFDVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;
                col_PEPT(reshape(app.PEPTVoxNPasses,[],1)<app.OutlierPassThreshold) = NaN;

                % fitting a linear model
                mdl = fitlm(col_CFD,col_PEPT);
                R2 = mdl.Rsquared.Ordinary;

                if max(col_CFD) > max(col_PEPT)
                    xmax = max(col_CFD);
                    ymax = max(col_CFD);
                else
                    xmax = max(col_PEPT);
                    ymax = max(col_PEPT);
                end

                if min(col_CFD) < min(col_PEPT)
                    xmin = min(col_CFD);
                    ymin = min(col_CFD);
                else
                    xmin = min(col_PEPT);
                    ymin = min(col_PEPT);
                end

                xval = linspace(xmin,xmax,10);
                reg = mdl.Coefficients{1,1}+xval.*mdl.Coefficients{2,1};
                CI = coefCI(mdl,0.05);
                ylow = CI(1,1) + CI(2,1)*xval;
                yupp = CI(1,2) + CI(2,2)*xval;


                % plot
                plot(app.UIAxesParityMixEff,col_CFD,col_PEPT,'.k',xval,reg,'-k',x45,y45,'-r');
                hold(app.UIAxesParityMixEff,'on');
                pt = patch(app.UIAxesParityMixEff,[xval fliplr(xval)], [ylow fliplr(yupp)], 'k','EdgeColor','none');
                hold(app.UIAxesParityMixEff,'off');
                pt.FaceAlpha = 0.15;
                str1 = string(strcat('\ $r^2 = $',{' '},num2str(R2,'%.2g')));
                %annotation(app.UIAxesParityVelocity,'textbox',[0.15,0.7,0.2,0.2],'String',{str1},'FitBoxToText','on','BackgroundColor','w','fontsize',16,'Interpreter','latex');
                % annotations dont work with UIAxes... find a workaround...
                % Graph properties
                set(app.UIAxesParityMixEff,'fontsize',14,'TickLabelInterpreter','latex');
                title(app.UIAxesParityMixEff,str1,'fontsize',32,'Interpreter','latex');
                app.UIAxesParityMixEff.PlotBoxAspectRatio = [1 1 1];


                %grid minor;
                axis(app.UIAxesParityMixEff,[app.parityMinMixEff app.parityMaxMixEff app.parityMinMixEff app.parityMaxMixEff]); %0.25 is tipspeed
                xlabel(app.UIAxesParityMixEff,'CFD Normalised ME (-)','fontsize',12);
                ylabel(app.UIAxesParityMixEff,'PEPT Normalised ME (-)','fontsize',12);
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'Mix-eff parity plot error','Icon','error');
                return
            end
        end
        function PlotMixEffCFDSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Normalised ME (-)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.CFDgif_order_z)

                    while app.CFDgif_order_z(i)==app.CFDgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxMixEffNormNaN,[2 1 3]),[],[],app.CFDgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Normalised ME (-)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.CFDgif_order_y)

                    while app.CFDgif_order_y(i)==app.CFDgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxMixEffNormNaN,[2 1 3]),[],app.CFDgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Normalised ME (-)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.CFDgif_order_x)

                    while app.CFDgif_order_x(i)==app.CFDgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.CFDslice_xrange,app.CFDslice_yrange,app.CFDslice_zrange,permute(app.CFDVoxMixEffNormNaN,[2 1 3]),app.CFDgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Normalised ME (-)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.CFDgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'CFD mix-eff tomography error','Icon','error');
                return
            end
        end
        function PlotMixEffPEPTSlices(app,file)
            try
                slice_img = figure(5);
                c = colorbar; c.Label.String = 'Normalised ME (-)';
                colormap jet
                set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                set(gcf,'color','w','position',[100,100,600,500]);
                camup([0 1 0]);
                daspect([1,1,1]);
                camproj('orthographic');
                campos([-1,1,1]);
                xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                ztickformat('%.2f')
                %grid minor;
                caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                grid off; %grid minor;

                % z direction
                %filename = 'Velocity_xy';
                for i = 1:1:length(app.PEPTgif_order_z)

                    while app.PEPTgif_order_z(i)==app.PEPTgif_range_z(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxMixEffNormNaN,[2 1 3]),[],[],app.PEPTgif_order_z(i));
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Normalised ME (-)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_z(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    if i == 1
                        gif(file,'overwrite',true,'DelayTime',1./app.gifFrameRate,'LoopCount',inf,'DitherOption','nodither')

                    else
                        gif()
                    end
                end

                for i = 1:1:length(app.PEPTgif_order_y)

                    while app.PEPTgif_order_y(i)==app.PEPTgif_range_y(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxMixEffNormNaN,[2 1 3]),[],app.PEPTgif_order_y(i),[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Normalised ME (-)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_y(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                for i = 1:1:length(app.PEPTgif_order_x)

                    while app.PEPTgif_order_x(i)==app.PEPTgif_range_x(1) % section going downwards
                        i = i+1;
                    end
                    zslice = slice(app.PEPTslice_xrange,app.PEPTslice_yrange,app.PEPTslice_zrange,permute(app.PEPTVoxMixEffNormNaN,[2 1 3]),app.PEPTgif_order_x(i),[],[]);
                    set(zslice,'edgecolor','none');

                    c = colorbar; c.Label.String = 'Normalised ME (-)';
                    colormap jet
                    set(gca,'ZDir','reverse','fontsize',14,'TickLabelInterpreter','latex');
                    set(gcf,'color','w','position',[100,100,600,500]);
                    camup([0 1 0]);
                    daspect([1,1,1]);
                    camproj('orthographic');
                    campos([-1,1,1]);
                    xlabel('Position in x [m]','fontsize',14,'fontweight','b')
                    ylabel('Position in y [m]','fontsize',14,'fontweight','b')
                    zlabel('Position in z [m]','fontsize',14,'fontweight','b')
                    xtickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ytickformat('%.2f') %specifies tick value's number of dp (2 = 2 dp)
                    ztickformat('%.2f')
                    %grid minor;
                    caxis([app.Mixeffmin app.Mixeffmax]); %specifies number and value of ticks for colorbar axis
                    axis([-app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2 -app.SizeEquip/2 app.SizeEquip/2]);
                    grid off; %grid minor;

                    str = {strcat('Z =  ',num2str(app.PEPTgif_order_x(i),2),' m')};
                    title(str,'fontsize',16,'BackgroundColor','w');

                    gif()
                end

                close(slice_img)
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'PEPT mix-eff tomography error','Icon','error');
                return
            end
        end

        % Exporting figures
        function ExportFig(app,id)
            try
                % exports both CFD and PEPT plots as both .fig and .png,
                % including parity plots
                % Option: export tomography as gif
                cfdplot = 0;
                peptplot = 0;
                switch id
                    case 1
                        savePlotCFD = app.UIAxesCFDVelocity;
                        savePlotPEPT = app.UIAxesPEPTVelocity;
                        savePlotParity = app.UIAxesParityVelocity;
                        textAppend = "_velocity";
                    case 2
                        savePlotCFD = app.UIAxesCFDNPasses;
                        savePlotPEPT = app.UIAxesPEPTNPasses;
                        textAppend = "_npasses";
                    case 3
                        savePlotCFD = app.UIAxesCFDOccupancy;
                        savePlotPEPT = app.UIAxesPEPTOccupancy;
                        savePlotParity = app.UIAxesParityVelocity;
                        textAppend = "_occupancy";
                    case 4
                        savePlotCFD = app.UIAxesCFDDispersion;
                        savePlotPEPT = app.UIAxesPEPTDispersion;
                        savePlotParity = app.UIAxesParityVelocity;
                        textAppend = "_dispersion";
                    case 5
                        savePlotCFD = app.UIAxesCFDDisplacement;
                        savePlotPEPT = app.UIAxesPEPTDisplacement;
                        savePlotParity = app.UIAxesParityVelocity;
                        textAppend = "_displacement";
                    case 6
                        savePlotCFD = app.UIAxesCFDMixEff;
                        savePlotPEPT = app.UIAxesPEPTMixEff;
                        savePlotParity = app.UIAxesParityVelocity;
                        textAppend = "_mixingeffectiveness";
                    case 7
                        textAppend = "_velocity_tomography";
                    case 8
                        textAppend = "_npasses_tomography";
                    case 9
                        textAppend = "_occupancy_tomography";
                    case 10
                        textAppend = "_dispersion_tomography";
                    case 11
                        textAppend = "_displacement_tomography";
                    case 12
                        textAppend = "_mixeff_tomography";
                    case 13
                        textAppend = "_velocity_comparison";
                end

                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                if id >=7
                    filter = {'*.gif'};
                else
                    filter = {'*.png';'*.jpg';'*.fig'};
                end

                if isempty(app.CFDMappedF) == 0
                    cfdplot = 1;
                    [name, path, index] = uiputfile(filter,'Export figure',strcat(app.CFDName,'_',num2str(app.NVox),'voxels',textAppend));
                    if index == 0
                        delete(f);
                        return
                    elseif ischar(name)
                        if index ==3
                            saveas(savePlotCFD,[path name],'fig'); %saves tiffs
                        elseif id >=7 && index ==1 % Checks that gif export option is selected and input from UIBox
                            switch id
                                case 7
                                    PlotVelocityCFDSlices(app,[path,filesep,name]); % Now activates tomography function
                                    delete(f);
                                case 8
                                    PlotNPassesCFDSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 9
                                    PlotOccupancyCFDSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 10
                                    PlotDispersionCFDSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 11
                                    PlotDisplacementCFDSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 12
                                    PlotMixEffCFDSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 13
                                    CompareVelocitySlices(app,[path,filesep,name]);
                                    delete(f);
                            end
                        else
                            exportgraphics(savePlotCFD,[path name],"BackgroundColor","none","Resolution",300); %saves tiffs
                        end
                    end
                end
                if isempty(app.PEPTMappedF) == 0
                    peptplot = 1;
                    [name, path, index] = uiputfile(filter,'Export figure',strcat(app.PEPTName,'_',num2str(app.NVox),'voxels',textAppend));
                    if index == 0
                        delete(f);
                        return
                    elseif ischar(name)
                        if index ==3
                            saveas(savePlotPEPT,[path name],'fig'); %saves tiffs
                        elseif id >=7 && index ==1 % Checks that gif export option is selected and input from UIBox
                            switch id
                                case 7
                                    PlotVelocityPEPTSlices(app,[path,filesep,name]); % Now activates tomography function
                                    delete(f);
                                case 8
                                    PlotNPassesPEPTSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 9
                                    PlotOccupancyPEPTSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 10
                                    PlotDispersionPEPTSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 11
                                    PlotDisplacementPEPTSlices(app,[path,filesep,name]);
                                    delete(f);
                                case 12
                                    PlotMixEffPEPTSlices(app,[path,filesep,name]);
                                    delete(f);
                            end
                        else
                            exportgraphics(savePlotPEPT,[path name],"BackgroundColor","none","Resolution",300); %saves tiffs
                        end
                    end
                end

                if (cfdplot && peptplot == 1) && (id ~= 2 && id ~= 7)
                    [name, path, index] = uiputfile(filter,'Export parity plot',strcat(app.CFDName,'_',app.PEPTName,'_',num2str(app.NVox),'voxels',textAppend,'_Parityplot'));
                    if index == 0
                        delete(f);
                        return
                    elseif ischar(name)
                        if index ==3
                            saveas(savePlotParity,[path name],'fig'); %saves tiffs
                        else
                            exportgraphics(savePlotParity,[path name],"BackgroundColor","none","Resolution",300); %saves tiffs
                        end
                    end
                end
                delete(f); %delete the dummy figure
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'File export error','Icon','error');
                return
            end
        end
        function ExportData(app,id)
            try
                % exports both CFD and PEPT data as .csv file
                % including parity plot data

                switch id
                    case 1 % velocity data exported
                        variable = app.CFDVoxVelocity;
                        title = app.CFDName;
                        textAppend = "_velocity";
                    case 2
                        variable = app.PEPTVoxVelocity;
                        title = app.PEPTName;
                        textAppend = "_velocity";
                    case 3
                        variable = [string(app.CFDName), string(app.PEPTName); arrayfun(@num2str,app.parity_velocity,'UniformOutput',0)];
                        title = strcat(app.CFDName,'_',app.PEPTName);
                        textAppend = "_velocity_parity_plot";
                    case 4 % Npasses data exported
                        variable = app.CFDVoxNPasses;
                        title = app.CFDName;
                        textAppend = "_Npasses";
                    case 5
                        variable = app.PEPTVoxNPasses;
                        title = app.PEPTName;
                        textAppend = "_Npasses";
                    case 6 % Occupancy data exported
                        variable = app.CFDVoxOccupancy;
                        title = app.CFDName;
                        textAppend = "_occupancy";
                    case 7
                        variable = app.PEPTVoxOccupancy;
                        title = app.PEPTName;
                        textAppend = "_occupancy";
                    case 8
                        variable = [string(app.CFDName), string(app.PEPTName); arrayfun(@num2str,app.parity_velocity,'UniformOutput',0)];
                        title = strcat(app.CFDName,'_',app.PEPTName);
                        textAppend = "_occupancy_parity_plot";


                end


                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

                %%% workspace
                [name, path, index] = uiputfile('*.csv','Export data',strcat(title,'_',num2str(app.NVox),'voxels',textAppend));
                if index == 0
                    delete(f);
                    return

                elseif ischar(name)
                    % Write to a CSV file
                    writematrix(variable, [path name]);
                end

                delete(f); %delete the dummy figure

                %%% end workspace
            catch ME
                message = '';
                for line = 1:size(ME.stack(:,1))
                    message = sprintf('%s%s',message,sprintf('Error in %s on line %i.\r\n',ME.stack(line).name,ME.stack(line).line));
                end
                message = sprintf('%s%s',message,sprintf('\r\n%s',ME.message));
                uialert(app.UIFigure,message,'Data export error','Icon','error');
                return
            end
        end
        function gif(varargin)
            % Define persistent variables:
            persistent gif_filename firstframe DelayTime DitherOption LoopCount frame resolution

            %% Parse Inputs

            if nargin>0

                % The user may want to clear things and start over:
                if any(strcmpi(varargin,'clear'))

                    % Clear persistent variables associated with this function:
                    clear gif_filename firstframe DelayTime DitherOption LoopCount frame resolution
                end

                % If the first input ends in .gif, assume this is the first frame:
                if strcmpi(varargin{1}(end-3:end),'.gif')

                    % This is what the user wants to call the new .gif file:
                    gif_filename = varargin{1};

                    % Check for an existing .gif file by the same name:
                    if exist(gif_filename,'file')==2

                        OverWrite = false; % By default, do NOT overwrite an existing file by the input name.
                        if nargin>1
                            tmp = strncmpi(varargin,'overwrite',4);
                            if any(tmp)
                                OverWrite = varargin{find(tmp)+1};
                                assert(islogical(OverWrite),'Error: Overwrite input must be either true or false.')
                            end
                        end

                        if ~OverWrite

                            % Ask the user if (s)he wants to overwrite the existing file:
                            choice = questdlg(['The file  ',gif_filename,' already exists. Overwrite it?'], ...
                                'The file already exists.','Overwrite','Cancel','Cancel');
                            if strcmp(choice,'Overwrite')
                                OverWrite = true;
                            end
                        end

                        % Overwriting basically means deleting and starting from scratch:
                        if OverWrite
                            delete(gif_filename)
                        else
                            clear gif_filename firstframe DelayTime DitherOption LoopCount frame
                            error('The giffing has been canceled.')
                        end

                    end

                    firstframe = true;

                    % Set defaults:
                    DelayTime = 1;
                    DitherOption = 'nodither';
                    LoopCount = Inf;
                    frame = gcf;
                    resolution = 0; % When 0, it's used as a boolean to say "don't use export_fig". If greater than zero, the boolean says "use export_fig and use the specified resolution."
                end

                tmp = strcmpi(varargin,'DelayTime');
                if any(tmp)
                    DelayTime = varargin{find(tmp)+1};
                    assert(isscalar(DelayTime),'Error: DelayTime must be a scalar value.')
                end

                if any(strcmpi(varargin,'nodither'))
                    DitherOption = 'nodither';
                end

                tmp = strcmpi(varargin,'LoopCount');
                if any(tmp)
                    LoopCount = varargin{find(tmp)+1};
                    assert(isscalar(LoopCount),'Error: LoopCount must be a scalar value.')
                end

                tmp = strncmpi(varargin,'resolution',3);
                if any(tmp)
                    resolution = varargin{find(tmp)+1};
                    assert(isscalar(resolution),'Error: resolution must be a scalar value.')
                    assert(exist('export_fig.m','file')==2,'export_fig not found. If you wish to specify the image resolution, get export_fig here :https://www.mathworks.com/matlabcentral/fileexchange/23629. Otherwise remove the resolution from the gif inputs to use the default (lower quality) built-in getframe functionality.')
                    warning off export_fig:exportgraphics
                end

                tmp = strcmpi(varargin,'frame');
                if any(tmp)
                    frame = varargin{find(tmp)+1};
                    assert(ishandle(frame)==1,'Error: frame must be a figure handle or axis handle.')
                end

            else
                assert(isempty(gif_filename)==0,'Error: The first call of the gif function requires a filename ending in .gif.')
            end

            %% Perform work:

            if resolution % If resolution is >0, it means use export_fig

                if isgraphics(frame,'figure')
                    f = export_fig('-nocrop',['-r',num2str(resolution)]);
                else
                    % If the frame is a set of axes instead of a figure, use default cropping:
                    f = export_fig(['-r',num2str(resolution)]);
                end

            else
                % Get frame:
                fr = getframe(frame);
                f =  fr.cdata;
            end

            % Convert the frame to a colormap and corresponding indices:
            [imind,cmap] = rgb2ind(f,256,DitherOption);

            % Write the file:
            if firstframe
                imwrite(imind,cmap,gif_filename,'gif','LoopCount',LoopCount,'DelayTime',DelayTime)
                firstframe = false;
            else
                imwrite(imind,cmap,gif_filename,'gif','WriteMode','append','DelayTime',DelayTime)
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NumberofvoxelsEditFieldValueChanged(app, event)
            value = app.NumberofvoxelsEditField.Value;
            app.NVox = value;
        end

        % Callback function
        function CharacteristiclengthmEditFieldValueChanged(app, event)
            value = app.CharacteristiclengthmEditField.Value;
            app.SizeEquip = value;
        end

        % Menu selected function: CFDTrajectoriesMenu
        function CFDTrajectoriesMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.CFDLoad;
            if app.LoadCFD == true
                message = {'Raw Data loaded successfully'};
                uialert(app.UIFigure,message,'CFD import','Icon','success');
            end
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            return
        end

        % Menu selected function: PEPTTrajectoriesMenu
        function PEPTTrajectoriesMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.PEPTLoad;
            if app.LoadPEPT == true
                message = {'Raw Data loaded successfully'};
                uialert(app.UIFigure,message,'PEPT import','Icon','success');
            end
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Callback function
        function Velocityplot(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            cfdplot = 0;
            peptplot = 0;
            if isempty(app.CFDMappedF) == 0
                % plot CFD velocity voxels
                app.PlotVelocityCFDVox
                cfdplot = 1;
            end
            if isempty(app.CFDMappedF) == 0

                app.PlotVelocityPEPTVox
                cfdplot = 1;
            end
            if cfdplot && peptplot ==1

            end
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";


        end

        % Menu selected function: CFDMenu
        function CFDMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            cla(app.UIAxesCFDVelocity);
            app.CFDVelocity3;
            app.VoxelBinsCFD;
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            message = {'Velocities calculated successfully'};
            uialert(app.UIFigure,message,'CFD calculation','Icon','success');

        end

        % Menu selected function: PEPTMenu
        function PEPTMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            cla(app.UIAxesPEPTVelocity);
            app.PEPTVelocity3
            app.VoxelBinsPEPT
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            message = {'Velocities calculated successfully'};
            uialert(app.UIFigure,message,'PEPT calculation','Icon','success');

        end

        % Callback function
        function CFDMenu_3Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            % plot CFD velocity voxels
            app.PlotVelocityCFDVox
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            % blank figure opens unexpectedly in both PEPT and CFD plots
        end

        % Callback function
        function PEPTMenu_3Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            % plot PEPT velocity voxels
            app.PlotVelocityPEPTVox
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Callback function
        function ParityplotsMenu_2Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            % plots parity plots
            app.ParityPlotVelocity
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Value changed function: CharacteristicLengthmEditField
        function CharacteristicLengthmEditFieldValueChanged(app, event)
            value = app.CharacteristicLengthmEditField.Value;
            app.SizeEquip = value;
        end

        % Button pushed function: UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)

            %             if app.MaxParity == app.MinParity
            %                 message = {'Parity plot axis min and max cannot be the same.'};
            %                 uialert(app.UIFigure,message,'Error','Icon','error');
            %             elseif app.MaxParity < app.MinParity
            %                 message = {'Axis min must be smaller than max'};
            %                 uialert(app.UIFigure,message,'Error','Icon','error');
            %             else
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            %                 app.CFDCalibration
            cfdplot = 0;
            peptplot = 0;
            if isempty(app.CFDCartesian) == 0
                app.VoxelBinsCFD;
                cla(app.UIAxesCFDVelocity);
                cla(app.UIAxesCFDNPasses);
                cla(app.UIAxesCFDOccupancy);
                cla(app.UIAxesCFDDispersion);
                cla(app.UIAxesCFDDisplacement);
                cla(app.UIAxesCFDMixEff);
                app.PlotVelocityCFDVox;
                app.PlotNPassesCFDVox;
                app.PlotOccupancyCFDVox;
                if app.RunDispersion ==1
                    app.PlotDispersionCFDVox;
                    app.PlotDisplacementCFDVox;
                    app.PlotMixEffCFDVox;
                end
                cfdplot = 1;
            end
            %                 app.PEPTCalibration

            if isempty(app.PEPTCartesian) == 0
                app.VoxelBinsPEPT;
                app.PEPTErr;
                cla(app.UIAxesPEPTVelocity);
                cla(app.UIAxesPEPTNPasses);
                cla(app.UIAxesPEPTOccupancy);
                cla(app.UIAxesPEPTDispersion);
                cla(app.UIAxesPEPTDisplacement);
                cla(app.UIAxesPEPTMixEff);
                app.PlotVelocityPEPTVox;
                app.PlotNPassesPEPTVox;
                app.PlotOccupancyPEPTVox;
                if app.RunDispersion ==1
                    app.PlotDispersionPEPTVox;
                    app.PlotDisplacementPEPTVox;
                    app.PlotMixEffPEPTVox
                end
                peptplot = 1;
            end
            if cfdplot && peptplot == 1
                cla(app.UIAxesParityVelocity);
                cla(app.UIAxesParityDispersion);
                cla(app.UIAxesParityDisplacement);
                cla(app.UIAxesParityMixEff);
                app.ParityPlotVelocity;
                app.ParityPlotOccupancy;
                if app.RunDispersion ==1
                    app.ParityPlotDispersion;
                    app.ParityPlotDisplacement;
                    app.ParityPlotMixEffNorm;
                end
            end
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

            drawnow
        end

        % Value changed function: NumberofvoxelsEditField
        function NumberofvoxelsEditFieldValueChanged2(app, event)
            value = app.NumberofvoxelsEditField.Value;
            app.NVox = value;
        end

        % Value changed function: FreesurfacecutoffmEditField
        function FreesurfacecutoffmEditFieldValueChanged(app, event)
            value = app.FreesurfacecutoffmEditField.Value;
            if app.CheckBox.Value == 1
                app.HeightCutOff = value;
            else
                app.HeightCutOff = [];
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.HeightCutOff = app.FreesurfacecutoffmEditField.Value;
            else
                app.HeightCutOff = [];
            end
        end

        % Value changed function: CFDdatacollectionstartsEditField
        function CFDdatacollectionstartsEditFieldValueChanged(app, event)
            value = app.CFDdatacollectionstartsEditField.Value;
            app.StartTime = value;
        end

        % Value changed function: ofPEPTdatatoreadEditField
        function ofPEPTdatatoreadEditFieldValueChanged(app, event)
            value = app.ofPEPTdatatoreadEditField.Value;
            if value >=1 && value <=100
                app.PEPTrowread = value;
            else
                message = {'% must be between 1-100'};
                uialert(app.UIFigure,message,'Input error','Icon','error');
                return
            end

        end

        % Button pushed function: CalculatevelocitiesButton
        function CalculatevelocitiesButtonPushed(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            cfdplot = 0;
            peptplot = 0;
            if isempty(app.CFDTraj) ==0
                app.CFDVelocity3;
                % app.CFDCalibration
                app.VoxelBinsCFD;
                app.CFDErr;
                cla(app.UIAxesCFDVelocity);
                cla(app.UIAxesCFDNPasses);
                cla(app.UIAxesCFDOccupancy);
                cla(app.UIAxesCFDDispersion);
                cla(app.UIAxesCFDDisplacement);
                cla(app.UIAxesCFDMixEff);
                app.PlotVelocityCFDVox;
                app.PlotNPassesCFDVox;
                app.PlotOccupancyCFDVox;
                if app.RunDispersion ==1
                    app.PlotDispersionCFDVox;
                    app.PlotDisplacementCFDVox;
                    app.PlotMixEffCFDVox;
                end
                cfdplot = 1;
            end

            if isempty(app.PEPTTraj) ==0
                app.PEPTVelocity3;
                % app.PEPTCalibration
                app.VoxelBinsPEPT;
                app.PEPTErr;
                cla(app.UIAxesPEPTVelocity);
                cla(app.UIAxesPEPTNPasses);
                cla(app.UIAxesPEPTOccupancy);
                cla(app.UIAxesPEPTDispersion);
                cla(app.UIAxesPEPTDisplacement);
                cla(app.UIAxesPEPTMixEff);
                app.PlotVelocityPEPTVox;
                app.PlotNPassesPEPTVox;
                app.PlotOccupancyPEPTVox;
                if app.RunDispersion ==1
                    app.PlotDispersionPEPTVox;
                    app.PlotDisplacementPEPTVox;
                    app.PlotMixEffPEPTVox
                end
                peptplot = 1;
            end

            if cfdplot && peptplot ==1
                cla(app.UIAxesParityVelocity);
                cla(app.UIAxesParityDispersion);
                cla(app.UIAxesParityDisplacement);
                cla(app.UIAxesParityMixEff);
                app.SimulationQuality;
                app.ParityPlotVelocity;
                app.ParityPlotOccupancy;
                if app.RunDispersion ==1
                    app.ParityPlotDispersion;
                    app.ParityPlotDisplacement;
                    app.ParityPlotMixEffNorm;
                end
            end

            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            drawnow
        end

        % Value changed function: ofCFDdatatoreadEditField
        function ofCFDdatatoreadEditFieldValueChanged(app, event)
            value = app.ofCFDdatatoreadEditField.Value;
            if value >=1 && value <=100
                app.CFDRowRead = value;
            else
                message = {'% must be between 1-100'};
                uialert(app.UIFigure,message,'Input error','Icon','error');
                return
            end

        end

        % Callback function
        function ParityplotXYminEditFieldValueChanged(app, event)
            value = app.ParityplotXYminEditField.Value;
            app.MinParity = value;
        end

        % Callback function
        function ParityplotXYmaxEditFieldValueChanged(app, event)
            value = app.ParityplotXYmaxEditField.Value;
            app.MaxParity = value;
        end

        % Value changed function: SavitzkyGolayFramelengthEditField
        function SavitzkyGolayFramelengthEditFieldValueChanged(app, event)
            value = app.SavitzkyGolayFramelengthEditField.Value;
            app.FrameLength = value;
        end

        % Menu selected function: VelocityMenu
        function VelocityMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(1)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

        end

        % Menu selected function: CleardataMenu
        function CleardataMenuSelected(app, event)
            % Clear CFD plots
            cla(app.UIAxesCFDVelocity,'reset');
            cla(app.UIAxesCFDNPasses,'reset');
            cla(app.UIAxesCFDOccupancy,'reset');
            cla(app.UIAxesCFDDispersion,'reset');
            cla(app.UIAxesCFDDisplacement,'reset');
            cla(app.UIAxesCFDMixEff,'reset');

            % Clear PEPT plots
            cla(app.UIAxesPEPTVelocity,'reset');
            cla(app.UIAxesPEPTNPasses,'reset');
            cla(app.UIAxesPEPTOccupancy,'reset');
            cla(app.UIAxesPEPTDispersion,'reset');
            cla(app.UIAxesPEPTDisplacement,'reset');
            cla(app.UIAxesPEPTMixEff,'reset');

            % Clear Parity plots
            cla(app.UIAxesParityVelocity,'reset');
            cla(app.UIAxesParityOccupancy,'reset');
            cla(app.UIAxesParityDispersion,'reset');
            cla(app.UIAxesParityDisplacement,'reset');
            cla(app.UIAxesParityMixEff,'reset');

            clc; clear;
        end

        % Callback function
        function ColourbarminEditFieldValueChanged(app, event)
            value = app.ColourbarminEditField.Value;
            app.UMin = value;
        end

        % Callback function
        function ColourbarmaxEditFieldValueChanged(app, event)
            value = app.ColourbarmaxEditField.Value;
            app.UMax = value;
        end

        % Value changed function: DispersiontimemaxsEditField
        function DispersiontimemaxsEditFieldValueChanged(app, event)
            value = app.DispersiontimemaxsEditField.Value;
            app.DispersionMaxT = value;
        end

        % Value changed function: DispersiontimeintervalsEditField
        function DispersiontimeintervalsEditFieldValueChanged(app, event)
            value = app.DispersiontimeintervalsEditField.Value;
            app.DispTimeStep = value;
        end

        % Value changed function: ColourbarEditField_6
        function ColourbarEditField_6ValueChanged(app, event)
            value = app.ColourbarEditField_6.Value;
            app.Mixeffmin = value;
        end

        % Value changed function: ColourbarmaxEditField_2
        function ColourbarmaxEditField_2ValueChanged(app, event)
            value = app.ColourbarmaxEditField_2.Value;
            app.Mixeffmax = value;
        end

        % Value changed function: ColourbarEditField_5
        function ColourbarEditField_5ValueChanged(app, event)
            value = app.ColourbarEditField_5.Value;
            app.Displacementmin = value;
        end

        % Value changed function: ColourbarmaxEditField_3
        function ColourbarmaxEditField_3ValueChanged(app, event)
            value = app.ColourbarmaxEditField_3.Value;
            app.Displacementmax = value;
        end

        % Value changed function: ColourbarEditField_4
        function ColourbarEditField_4ValueChanged(app, event)
            value = app.ColourbarEditField_4.Value;
            app.Dispersionmin = value;
        end

        % Value changed function: ColourbarmaxEditField_4
        function ColourbarmaxEditField_4ValueChanged(app, event)
            value = app.ColourbarmaxEditField_4.Value;
            app.Dispersionmax = value;
        end

        % Value changed function: ColourbarminOcc
        function ColourbarminOccValueChanged(app, event)
            value = app.ColourbarminOcc.Value;
            app.Occmin = value;
        end

        % Value changed function: ColourbarmaxOcc
        function ColourbarmaxOccValueChanged(app, event)
            value = app.ColourbarmaxOcc.Value;
            app.Occmax = value;
        end

        % Value changed function: ColourbarminNpasses
        function ColourbarminNpassesValueChanged(app, event)
            value = app.ColourbarminNpasses.Value;
            app.Npassmin = value;
        end

        % Value changed function: ColourbarmaxNpasses
        function ColourbarmaxNpassesValueChanged(app, event)
            value = app.ColourbarmaxNpasses.Value;
            app.Npassmax = value;
        end

        % Value changed function: ColourbarEditField_2
        function ColourbarEditField_2ValueChanged(app, event)
            value = app.ColourbarEditField_2.Value;
            app.UMin = value;
        end

        % Value changed function: ParityplotEditField_2
        function ParityplotEditField_2ValueChanged(app, event)
            value = app.ParityplotEditField_2.Value;
            app.parityMinVelocity = value;
        end

        % Value changed function: ParityplotmaxEditField
        function ParityplotmaxEditFieldValueChanged(app, event)
            value = app.ParityplotmaxEditField.Value;
            app.parityMaxVelocity = value;
        end

        % Value changed function: ParityplotEditField
        function ParityplotEditFieldValueChanged(app, event)
            value = app.ParityplotEditField.Value;
            app.parityMinOccupancy = value;
        end

        % Value changed function: ParityplotmaxEditField_2
        function ParityplotmaxEditField_2ValueChanged(app, event)
            value = app.ParityplotmaxEditField_2.Value;
            app.parityMaxOccupancy = value;
        end

        % Value changed function: ParityplotEditField_3
        function ParityplotEditField_3ValueChanged(app, event)
            value = app.ParityplotEditField_3.Value;
            app.parityMinDispersion = value;
        end

        % Value changed function: ParityplotmaxEditField_3
        function ParityplotmaxEditField_3ValueChanged(app, event)
            value = app.ParityplotmaxEditField_3.Value;
            app.parityMaxDispersion = value;
        end

        % Value changed function: ParityplotEditField_4
        function ParityplotEditField_4ValueChanged(app, event)
            value = app.ParityplotEditField_4.Value;
            app.parityMinDisplacement = value;
        end

        % Value changed function: ParityplotmaxEditField_4
        function ParityplotmaxEditField_4ValueChanged(app, event)
            value = app.ParityplotmaxEditField_4.Value;
            app.parityMaxDisplacement = value;
        end

        % Value changed function: ParityplotEditField_5
        function ParityplotEditField_5ValueChanged(app, event)
            value = app.ParityplotEditField_5.Value;
            app.parityMinMixEff = value;
        end

        % Value changed function: ParityplotmaxEditField_5
        function ParityplotmaxEditField_5ValueChanged(app, event)
            value = app.ParityplotmaxEditField_5.Value;
            app.parityMaxMixEff = value;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            app.RunDispersion = value; % if calculate dispersion checkbox is ticket, run dispersion calc
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            app.ThresholdOcc = value;
        end

        % Value changed function: ColourbarmaxEditField_7
        function ColourbarmaxEditField_7ValueChanged(app, event)
            value = app.ColourbarmaxEditField_7.Value;
            app.UMax = value;
        end

        % Value changed function: ThresholdEditField_4
        function ThresholdEditField_4ValueChanged(app, event)
            value = app.ThresholdEditField_4.Value;
            app.ThresholdVelmin = value;
        end

        % Value changed function: ThresholdEditField_3
        function ThresholdEditField_3ValueChanged(app, event)
            value = app.ThresholdEditField_3.Value;
            app.ThresholdVelmax = value;
        end

        % Value changed function: ThresholdEditField_6
        function ThresholdEditField_6ValueChanged(app, event)
            value = app.ThresholdEditField_6.Value;
            app.ThresholdNpassmin = value;
        end

        % Value changed function: ThresholdEditField_5
        function ThresholdEditField_5ValueChanged(app, event)
            value = app.ThresholdEditField_5.Value;
            app.ThresholdNpassmax = value;
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.ThresholdOccmin = value;
        end

        % Value changed function: ThresholdEditField_2
        function ThresholdEditField_2ValueChanged(app, event)
            value = app.ThresholdEditField_2.Value;
            app.ThresholdOccmax = value;
        end

        % Value changed function: ThresholdEditField_8
        function ThresholdEditField_8ValueChanged(app, event)
            value = app.ThresholdEditField_8.Value;
            app.ThresholdDispersionmin = value;
        end

        % Value changed function: ThresholdEditField_7
        function ThresholdEditField_7ValueChanged(app, event)
            value = app.ThresholdEditField_7.Value;
            app.ThresholdDispersionmax = value;
        end

        % Value changed function: ThresholdEditField_10
        function ThresholdEditField_10ValueChanged(app, event)
            value = app.ThresholdEditField_10.Value;
            app.ThresholdDisplacementmin = value;
        end

        % Value changed function: ThresholdEditField_9
        function ThresholdEditField_9ValueChanged(app, event)
            value = app.ThresholdEditField_9.Value;
            app.ThresholdDisplacementmax = value;
        end

        % Value changed function: ThresholdEditField_12
        function ThresholdEditField_12ValueChanged(app, event)
            value = app.ThresholdEditField_12.Value;
            app.ThresholdMixEffmin = value;
        end

        % Value changed function: ThresholdEditField_11
        function ThresholdEditField_11ValueChanged(app, event)
            value = app.ThresholdEditField_11.Value;
            app.ThresholdMixEffmax = value;
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value; %velocity
            app.ThresholdVel = value;
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            app.ThresholdNpass = value;
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            app.ThresholdDispersion = value;
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            app.ThresholdDisplacement = value;
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            app.ThresholdMixEff = value;
        end

        % Value changed function: VoxelsalphaEditField
        function VoxelsalphaEditFieldValueChanged(app, event)
            value = app.VoxelsalphaEditField.Value;
            if value <= 1 && value >= 0
                app.VoxelsAlpha = value;
            else
                message = {'Alpha must be between 0 and 1.'};
                uialert(app.UIFigure,message,'Input error','Icon','error');
                return
            end
        end

        % Value changed function: xcalibpept
        function xcalibpeptValueChanged(app, event)
            value = app.xcalibpept.Value;
            app.xCalibPEPT = value;
        end

        % Value changed function: xcalibcfd
        function xcalibcfdValueChanged(app, event)
            value = app.xcalibcfd.Value;
            app.xCalibCFD = value;
        end

        % Value changed function: ycalibpept
        function ycalibpeptValueChanged(app, event)
            value = app.ycalibpept.Value;
            app.yCalibPEPT = value;
        end

        % Value changed function: ycalibcfd
        function ycalibcfdValueChanged(app, event)
            value = app.ycalibcfd.Value;
            app.yCalibCFD = value;
        end

        % Value changed function: zcalibpept
        function zcalibpeptValueChanged(app, event)
            value = app.zcalibpept.Value;
            app.zCalibPEPT = value;
        end

        % Value changed function: zcalibcfd
        function zcalibcfdValueChanged(app, event)
            value = app.zcalibcfd.Value;
            app.zCalibCFD = value;
        end

        % Value changed function: MaximumpassesforoutliersEditField
        function MaximumpassesforoutliersEditFieldValueChanged(app, event)
            value = app.MaximumpassesforoutliersEditField.Value;
            app.OutlierPassThreshold = value;
        end

        % Menu selected function: CFDMenu_4
        function CFDMenu_4Selected(app, event)
            % perform binning on CFD data
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow

            if isempty(app.CFDCartesian) ==0
                app.VoxelBinsCFD;
                cla(app.UIAxesCFDVelocity);
                cla(app.UIAxesCFDNPasses);
                cla(app.UIAxesCFDOccupancy);
                cla(app.UIAxesCFDDispersion);
                cla(app.UIAxesCFDDisplacement);
                cla(app.UIAxesCFDMixEff);
                app.PlotVelocityCFDVox;
                app.PlotNPassesCFDVox;
                app.PlotOccupancyCFDVox;
                if app.RunDispersion ==1
                    app.PlotDispersionCFDVox;
                    app.PlotDisplacementCFDVox;
                    app.PlotMixEffCFDVox;
                end
            end

            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            drawnow
        end

        % Menu selected function: PEPTMenu_4
        function PEPTMenu_4Selected(app, event)
            % perform binning on PEPT data
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow

            if isempty(app.PEPTCartesian) ==0
                app.PEPTVelocity3;
                % app.PEPTCalibration
                app.VoxelBinsPEPT;
                app.PEPTErr;
                cla(app.UIAxesPEPTVelocity);
                cla(app.UIAxesPEPTNPasses);
                cla(app.UIAxesPEPTOccupancy);
                cla(app.UIAxesPEPTDispersion);
                cla(app.UIAxesPEPTDisplacement);
                cla(app.UIAxesPEPTMixEff);
                app.PlotVelocityPEPTVox;
                app.PlotNPassesPEPTVox;
                app.PlotOccupancyPEPTVox;
                if app.RunDispersion ==1
                    app.PlotDispersionPEPTVox;
                    app.PlotDisplacementPEPTVox;
                    app.PlotMixEffPEPTVox
                end
            end

            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
            drawnow
        end

        % Menu selected function: NPassesMenu
        function NPassesMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(2)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

        end

        % Menu selected function: OccupancyMenu
        function OccupancyMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(3)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

        end

        % Menu selected function: DispersionMenu
        function DispersionMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(4)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

        end

        % Menu selected function: DisplacementMenu
        function DisplacementMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(5)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

        end

        % Menu selected function: MixingEffectivenessMenu
        function MixingEffectivenessMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(6)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";

        end

        % Value changed function: FlipPEPTx
        function FlipPEPTxValueChanged(app, event)
            value = app.FlipPEPTx.Value;
            app.flippeptx = value;
        end

        % Value changed function: FlipCFDx
        function FlipCFDxValueChanged(app, event)
            value = app.FlipCFDx.Value;
            app.flipcfdx = value;
        end

        % Value changed function: FlipCFDy
        function FlipCFDyValueChanged(app, event)
            value = app.FlipCFDy.Value;
            app.flipcfdy = value;
        end

        % Value changed function: FlipCFDz
        function FlipCFDzValueChanged(app, event)
            value = app.FlipCFDz.Value;
            app.flipcfdz = value;
        end

        % Value changed function: FlipPEPTy
        function FlipPEPTyValueChanged(app, event)
            value = app.FlipPEPTy.Value;
            app.flippepty = value;
        end

        % Value changed function: FlipPEPTz
        function FlipPEPTzValueChanged(app, event)
            value = app.FlipPEPTz.Value;
            app.flippeptz = value;
        end

        % Selection changed function: PEPTTransposeY
        function PEPTTransposeYSelectionChanged(app, event)
            selectedButton = app.PEPTTransposeY.SelectedObject;
        end

        % Selection changed function: PEPTTransposeX
        function PEPTTransposeXSelectionChanged(app, event)
            selectedButton = app.PEPTTransposeX.SelectedObject;

        end

        % Selection changed function: PEPTTransposeZ
        function PEPTTransposeZSelectionChanged(app, event)
            selectedButton = app.PEPTTransposeZ.SelectedObject;

        end

        % Selection changed function: CFDTransposeX
        function CFDTransposeXSelectionChanged(app, event)
            selectedButton = app.CFDTransposeX.SelectedObject;

        end

        % Selection changed function: CFDTransposeY
        function CFDTransposeYSelectionChanged(app, event)
            selectedButton = app.CFDTransposeY.SelectedObject;

        end

        % Selection changed function: CFDTransposeZ
        function CFDTransposeZSelectionChanged(app, event)
            selectedButton = app.CFDTransposeZ.SelectedObject;

        end

        % Callback function
        function TomographyMenuSelected(app, event)

        end

        % Value changed function: TomographyframeratefpsEditField
        function TomographyframeratefpsEditFieldValueChanged(app, event)
            app.gifFrameRate = app.TomographyframeratefpsEditField.Value;
            if app.gifFrameRate >50
                app.gifFrameRate = 50;
            elseif app.gifFrameRate <1
                app.gifFrameRate = 1;
            end
        end

        % Menu selected function: VelocityTom
        function VelocityTomSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(7)
            app.ExportFig(13)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: NPassesTom
        function NPassesTomMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(8)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: OccupancyTom
        function OccupancyTomMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(9)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: DispersionTom
        function DispersionTomMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(10)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: DisplacementTom
        function DisplacementTomMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(11)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: MixingEffectivenessTom
        function MixingEffectivenessTomMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportFig(12)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Value changed function: LogColorbarOcc
        function LogColorbarOccValueChanged(app, event)
            app.LogScaleColorbar = app.LogColorbarOcc.Value;

        end

        % Callback function
        function PeptErrtestButtonPushed(app, event)
            app.PEPTErr
        end

        % Menu selected function: VelocityMenu_2
        function VelocityMenu_2Selected(app, event)

        end

        % Menu selected function: NPassesMenu_2
        function NPassesMenu_2Selected(app, event)

        end

        % Menu selected function: OccupancyMenu_2
        function OccupancyMenu_2Selected(app, event)

        end

        % Menu selected function: CFDVoxelsMenu_3
        function CFDVoxelsMenu_3Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(1)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: PEPTVoxelsMenu_3
        function PEPTVoxelsMenu_3Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(2)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: ParityplotMenu
        function ParityplotMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(3)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: CFDVoxelsMenu
        function CFDVoxelsMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(4)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: PEPTVoxelsMenu
        function PEPTVoxelsMenuSelected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(5)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: CFDVoxelsMenu_2
        function CFDVoxelsMenu_2Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(6)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: PEPTVoxelsMenu_2
        function PEPTVoxelsMenu_2Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(7)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end

        % Menu selected function: ParityplotMenu_2
        function ParityplotMenu_2Selected(app, event)
            app.Lamp.Color = 'red';
            app.LampLabel.Text = "Loading...";
            drawnow
            app.ExportData(8)
            app.Lamp.Color = 'green';
            app.LampLabel.Text = " ";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PEPT toolbox';
            app.UIFigure.Icon = 'icon_48.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.CFDTrajectoriesMenu = uimenu(app.ImportMenu);
            app.CFDTrajectoriesMenu.MenuSelectedFcn = createCallbackFcn(app, @CFDTrajectoriesMenuSelected, true);
            app.CFDTrajectoriesMenu.Text = 'CFD Trajectories';
            app.PEPTTrajectoriesMenu = uimenu(app.ImportMenu);
            app.PEPTTrajectoriesMenu.MenuSelectedFcn = createCallbackFcn(app, @PEPTTrajectoriesMenuSelected, true);
            app.PEPTTrajectoriesMenu.Text = 'PEPT Trajectories';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.FiguresMenu = uimenu(app.ExportMenu);
            app.FiguresMenu.Text = 'Figures';
            app.VelocityMenu = uimenu(app.FiguresMenu);
            app.VelocityMenu.MenuSelectedFcn = createCallbackFcn(app, @VelocityMenuSelected, true);
            app.VelocityMenu.Text = 'Velocity';
            app.NPassesMenu = uimenu(app.FiguresMenu);
            app.NPassesMenu.MenuSelectedFcn = createCallbackFcn(app, @NPassesMenuSelected, true);
            app.NPassesMenu.Text = 'N Passes';
            app.OccupancyMenu = uimenu(app.FiguresMenu);
            app.OccupancyMenu.MenuSelectedFcn = createCallbackFcn(app, @OccupancyMenuSelected, true);
            app.OccupancyMenu.Text = 'Occupancy';
            app.DispersionMenu = uimenu(app.FiguresMenu);
            app.DispersionMenu.MenuSelectedFcn = createCallbackFcn(app, @DispersionMenuSelected, true);
            app.DispersionMenu.Text = 'Dispersion';
            app.DisplacementMenu = uimenu(app.FiguresMenu);
            app.DisplacementMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplacementMenuSelected, true);
            app.DisplacementMenu.Text = 'Displacement';
            app.MixingEffectivenessMenu = uimenu(app.FiguresMenu);
            app.MixingEffectivenessMenu.MenuSelectedFcn = createCallbackFcn(app, @MixingEffectivenessMenuSelected, true);
            app.MixingEffectivenessMenu.Text = 'Mixing Effectiveness';
            app.DataMenu = uimenu(app.ExportMenu);
            app.DataMenu.Text = 'Data';
            app.VelocityMenu_2 = uimenu(app.DataMenu);
            app.VelocityMenu_2.MenuSelectedFcn = createCallbackFcn(app, @VelocityMenu_2Selected, true);
            app.VelocityMenu_2.Text = 'Velocity';
            app.CFDVoxelsMenu_3 = uimenu(app.VelocityMenu_2);
            app.CFDVoxelsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CFDVoxelsMenu_3Selected, true);
            app.CFDVoxelsMenu_3.Text = 'CFD Voxels';
            app.PEPTVoxelsMenu_3 = uimenu(app.VelocityMenu_2);
            app.PEPTVoxelsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @PEPTVoxelsMenu_3Selected, true);
            app.PEPTVoxelsMenu_3.Text = 'PEPT Voxels';
            app.ParityplotMenu = uimenu(app.VelocityMenu_2);
            app.ParityplotMenu.MenuSelectedFcn = createCallbackFcn(app, @ParityplotMenuSelected, true);
            app.ParityplotMenu.Text = 'Parity plot';
            app.NPassesMenu_2 = uimenu(app.DataMenu);
            app.NPassesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NPassesMenu_2Selected, true);
            app.NPassesMenu_2.Text = 'N Passes';
            app.CFDVoxelsMenu = uimenu(app.NPassesMenu_2);
            app.CFDVoxelsMenu.MenuSelectedFcn = createCallbackFcn(app, @CFDVoxelsMenuSelected, true);
            app.CFDVoxelsMenu.Text = 'CFD Voxels';
            app.PEPTVoxelsMenu = uimenu(app.NPassesMenu_2);
            app.PEPTVoxelsMenu.MenuSelectedFcn = createCallbackFcn(app, @PEPTVoxelsMenuSelected, true);
            app.PEPTVoxelsMenu.Text = 'PEPT Voxels';
            app.OccupancyMenu_2 = uimenu(app.DataMenu);
            app.OccupancyMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OccupancyMenu_2Selected, true);
            app.OccupancyMenu_2.Text = 'Occupancy';
            app.CFDVoxelsMenu_2 = uimenu(app.OccupancyMenu_2);
            app.CFDVoxelsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CFDVoxelsMenu_2Selected, true);
            app.CFDVoxelsMenu_2.Text = 'CFD Voxels';
            app.PEPTVoxelsMenu_2 = uimenu(app.OccupancyMenu_2);
            app.PEPTVoxelsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PEPTVoxelsMenu_2Selected, true);
            app.PEPTVoxelsMenu_2.Text = 'PEPT Voxels';
            app.ParityplotMenu_2 = uimenu(app.OccupancyMenu_2);
            app.ParityplotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ParityplotMenu_2Selected, true);
            app.ParityplotMenu_2.Text = 'Parity plot';
            app.TomographyMenu = uimenu(app.ExportMenu);
            app.TomographyMenu.Text = 'Tomography';
            app.VelocityTom = uimenu(app.TomographyMenu);
            app.VelocityTom.MenuSelectedFcn = createCallbackFcn(app, @VelocityTomSelected, true);
            app.VelocityTom.Text = 'Velocity';
            app.NPassesTom = uimenu(app.TomographyMenu);
            app.NPassesTom.MenuSelectedFcn = createCallbackFcn(app, @NPassesTomMenuSelected, true);
            app.NPassesTom.Text = 'N Passes';
            app.OccupancyTom = uimenu(app.TomographyMenu);
            app.OccupancyTom.MenuSelectedFcn = createCallbackFcn(app, @OccupancyTomMenuSelected, true);
            app.OccupancyTom.Text = 'Occupancy';
            app.DispersionTom = uimenu(app.TomographyMenu);
            app.DispersionTom.MenuSelectedFcn = createCallbackFcn(app, @DispersionTomMenuSelected, true);
            app.DispersionTom.Text = 'Dispersion';
            app.DisplacementTom = uimenu(app.TomographyMenu);
            app.DisplacementTom.MenuSelectedFcn = createCallbackFcn(app, @DisplacementTomMenuSelected, true);
            app.DisplacementTom.Text = 'Displacement';
            app.MixingEffectivenessTom = uimenu(app.TomographyMenu);
            app.MixingEffectivenessTom.MenuSelectedFcn = createCallbackFcn(app, @MixingEffectivenessTomMenuSelected, true);
            app.MixingEffectivenessTom.Text = 'Mixing Effectiveness';
            app.CleardataMenu = uimenu(app.FileMenu);
            app.CleardataMenu.MenuSelectedFcn = createCallbackFcn(app, @CleardataMenuSelected, true);
            app.CleardataMenu.Text = 'Clear data';
            app.AnalyseMenu = uimenu(app.UIFigure);
            app.AnalyseMenu.Text = 'Analyse';
            app.CalculatevelocitiesMenu = uimenu(app.AnalyseMenu);
            app.CalculatevelocitiesMenu.Text = 'Calculate velocities';
            app.CFDMenu = uimenu(app.CalculatevelocitiesMenu);
            app.CFDMenu.MenuSelectedFcn = createCallbackFcn(app, @CFDMenuSelected, true);
            app.CFDMenu.Text = 'CFD';
            app.PEPTMenu = uimenu(app.CalculatevelocitiesMenu);
            app.PEPTMenu.MenuSelectedFcn = createCallbackFcn(app, @PEPTMenuSelected, true);
            app.PEPTMenu.Text = 'PEPT';
            app.BinalgorithmMenu = uimenu(app.AnalyseMenu);
            app.BinalgorithmMenu.Text = 'Bin algorithm';
            app.CFDMenu_4 = uimenu(app.BinalgorithmMenu);
            app.CFDMenu_4.MenuSelectedFcn = createCallbackFcn(app, @CFDMenu_4Selected, true);
            app.CFDMenu_4.Text = 'CFD';
            app.PEPTMenu_4 = uimenu(app.BinalgorithmMenu);
            app.PEPTMenu_4.MenuSelectedFcn = createCallbackFcn(app, @PEPTMenu_4Selected, true);
            app.PEPTMenu_4.Text = 'PEPT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VelocityTab = uitab(app.TabGroup);
            app.VelocityTab.Title = 'Velocity';
            app.UIAxesCFDVelocity = uiaxes(app.VelocityTab);
            xlabel(app.UIAxesCFDVelocity, 'X')
            ylabel(app.UIAxesCFDVelocity, 'Y')
            zlabel(app.UIAxesCFDVelocity, 'Z')
            app.UIAxesPEPTVelocity = uiaxes(app.VelocityTab);
            xlabel(app.UIAxesPEPTVelocity, 'X')
            ylabel(app.UIAxesPEPTVelocity, 'Y')
            zlabel(app.UIAxesPEPTVelocity, 'Z')
            app.UIAxesParityVelocity = uiaxes(app.VelocityTab);
            xlabel(app.UIAxesParityVelocity, 'X')
            ylabel(app.UIAxesParityVelocity, 'Y')
            zlabel(app.UIAxesParityVelocity, 'Z')
            app.CFDLabelVelocity = uilabel(app.VelocityTab);
            app.CFDLabelVelocity.Text = 'CFD';
            app.PEPTLabelVelocity = uilabel(app.VelocityTab);
            app.PEPTLabelVelocity.Text = 'PEPT';
            app.ColourbarEditField_2Label = uilabel(app.VelocityTab);
            app.ColourbarEditField_2Label.Text = 'Colourbar';
            app.ColourbarEditField_2 = uieditfield(app.VelocityTab, 'numeric');
            app.ColourbarEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColourbarEditField_2ValueChanged, true);
            app.ColourbarmaxEditField_7 = uieditfield(app.VelocityTab, 'numeric');
            app.ColourbarmaxEditField_7.ValueChangedFcn = createCallbackFcn(app, @ColourbarmaxEditField_7ValueChanged, true);
            app.ColourbarmaxEditField_7.Value = 0.08;
            app.ParityplotEditField_2Label = uilabel(app.VelocityTab);
            app.ParityplotEditField_2Label.Text = 'Parity plot';
            app.ParityplotEditField_2 = uieditfield(app.VelocityTab, 'numeric');
            app.ParityplotEditField_2.ValueChangedFcn = createCallbackFcn(app, @ParityplotEditField_2ValueChanged, true);
            app.ParityplotmaxEditField = uieditfield(app.VelocityTab, 'numeric');
            app.ParityplotmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ParityplotmaxEditFieldValueChanged, true);
            app.ParityplotmaxEditField.Value = 1;
            app.ThresholdEditField_3 = uieditfield(app.VelocityTab, 'numeric');
            app.ThresholdEditField_3.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_3ValueChanged, true);
            app.MinLabel_3 = uilabel(app.VelocityTab);
            app.MinLabel_3.Text = 'Min';
            app.MaxLabel_3 = uilabel(app.VelocityTab);
            app.MaxLabel_3.Text = 'Max';
            app.CheckBox_4 = uicheckbox(app.VelocityTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.ThresholdEditField_4Label = uilabel(app.VelocityTab);
            app.ThresholdEditField_4Label.Text = 'Threshold';
            app.ThresholdEditField_4 = uieditfield(app.VelocityTab, 'numeric');
            app.ThresholdEditField_4.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_4ValueChanged, true);
            app.NumberofPassesTab = uitab(app.TabGroup);
            app.NumberofPassesTab.Title = 'Number of Passes';
            app.UIAxesCFDNPasses = uiaxes(app.NumberofPassesTab);
            xlabel(app.UIAxesCFDNPasses, 'X')
            ylabel(app.UIAxesCFDNPasses, 'Y')
            zlabel(app.UIAxesCFDNPasses, 'Z')
            app.UIAxesPEPTNPasses = uiaxes(app.NumberofPassesTab);
            xlabel(app.UIAxesPEPTNPasses, 'X')
            ylabel(app.UIAxesPEPTNPasses, 'Y')
            zlabel(app.UIAxesPEPTNPasses, 'Z')
            app.CFDLabelNPasses = uilabel(app.NumberofPassesTab);
            app.CFDLabelNPasses.Text = 'CFD';
            app.PEPTLabelNPasses = uilabel(app.NumberofPassesTab);
            app.PEPTLabelNPasses.Text = 'PEPT';
            app.ColourbarEditField_3Label = uilabel(app.NumberofPassesTab);
            app.ColourbarEditField_3Label.Text = 'Colourbar';
            app.ColourbarminNpasses = uieditfield(app.NumberofPassesTab, 'numeric');
            app.ColourbarminNpasses.ValueChangedFcn = createCallbackFcn(app, @ColourbarminNpassesValueChanged, true);
            app.ColourbarmaxNpasses = uieditfield(app.NumberofPassesTab, 'numeric');
            app.ColourbarmaxNpasses.ValueChangedFcn = createCallbackFcn(app, @ColourbarmaxNpassesValueChanged, true);
            app.ColourbarmaxNpasses.Value = 200;
            app.ThresholdEditField_5 = uieditfield(app.NumberofPassesTab, 'numeric');
            app.ThresholdEditField_5.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_5ValueChanged, true);
            app.MinLabel_4 = uilabel(app.NumberofPassesTab);
            app.MinLabel_4.Text = 'Min';
            app.MaxLabel_4 = uilabel(app.NumberofPassesTab);
            app.MaxLabel_4.Text = 'Max';
            app.CheckBox_5 = uicheckbox(app.NumberofPassesTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.ThresholdEditField_6Label = uilabel(app.NumberofPassesTab);
            app.ThresholdEditField_6Label.Text = 'Threshold';
            app.ThresholdEditField_6 = uieditfield(app.NumberofPassesTab, 'numeric');
            app.ThresholdEditField_6.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_6ValueChanged, true);
            app.OccupancyTab = uitab(app.TabGroup);
            app.OccupancyTab.Title = 'Occupancy';
            app.UIAxesCFDOccupancy = uiaxes(app.OccupancyTab);
            xlabel(app.UIAxesCFDOccupancy, 'X')
            ylabel(app.UIAxesCFDOccupancy, 'Y')
            zlabel(app.UIAxesCFDOccupancy, 'Z')
            app.UIAxesPEPTOccupancy = uiaxes(app.OccupancyTab);
            xlabel(app.UIAxesPEPTOccupancy, 'X')
            ylabel(app.UIAxesPEPTOccupancy, 'Y')
            zlabel(app.UIAxesPEPTOccupancy, 'Z')
            app.UIAxesParityOccupancy = uiaxes(app.OccupancyTab);
            xlabel(app.UIAxesParityOccupancy, 'X')
            ylabel(app.UIAxesParityOccupancy, 'Y')
            zlabel(app.UIAxesParityOccupancy, 'Z')
            app.CFDLabelOccupancy = uilabel(app.OccupancyTab);
            app.CFDLabelOccupancy.Text = 'CFD';
            app.PEPTLabelOccupancy = uilabel(app.OccupancyTab);
            app.PEPTLabelOccupancy.Text = 'PEPT';
            app.ColourbarEditFieldLabel = uilabel(app.OccupancyTab);
            app.ColourbarEditFieldLabel.Text = 'Colourbar';
            app.ColourbarminOcc = uieditfield(app.OccupancyTab, 'numeric');
            app.ColourbarminOcc.ValueChangedFcn = createCallbackFcn(app, @ColourbarminOccValueChanged, true);
            app.ColourbarminOcc.Value = 0.0001;
            app.ColourbarmaxOcc = uieditfield(app.OccupancyTab, 'numeric');
            app.ColourbarmaxOcc.ValueChangedFcn = createCallbackFcn(app, @ColourbarmaxOccValueChanged, true);
            app.ColourbarmaxOcc.Value = 0.001;
            app.ParityplotEditFieldLabel = uilabel(app.OccupancyTab);
            app.ParityplotEditFieldLabel.Text = 'Parity plot';
            app.ParityplotEditField = uieditfield(app.OccupancyTab, 'numeric');
            app.ParityplotEditField.ValueChangedFcn = createCallbackFcn(app, @ParityplotEditFieldValueChanged, true);
            app.ParityplotmaxEditField_2 = uieditfield(app.OccupancyTab, 'numeric');
            app.ParityplotmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ParityplotmaxEditField_2ValueChanged, true);
            app.ParityplotmaxEditField_2.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.OccupancyTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.OccupancyTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField_2 = uieditfield(app.OccupancyTab, 'numeric');
            app.ThresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_2ValueChanged, true);
            app.MinLabel_2 = uilabel(app.OccupancyTab);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.OccupancyTab);
            app.MaxLabel_2.Text = 'Max';
            app.CheckBox_3 = uicheckbox(app.OccupancyTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.LogColorbarOcc = uicheckbox(app.OccupancyTab);
            app.LogColorbarOcc.ValueChangedFcn = createCallbackFcn(app, @LogColorbarOccValueChanged, true);
            app.LogColorbarOcc.Text = '';
            app.LogColorbarOcc.Value = true;
            app.LogscaleLabel = uilabel(app.OccupancyTab);
            app.LogscaleLabel.Text = 'Log scale';
            app.DispersionTab = uitab(app.TabGroup);
            app.DispersionTab.Title = 'Dispersion';
            app.UIAxesCFDDispersion = uiaxes(app.DispersionTab);
            xlabel(app.UIAxesCFDDispersion, 'X')
            ylabel(app.UIAxesCFDDispersion, 'Y')
            zlabel(app.UIAxesCFDDispersion, 'Z')
            app.UIAxesPEPTDispersion = uiaxes(app.DispersionTab);
            xlabel(app.UIAxesPEPTDispersion, 'X')
            ylabel(app.UIAxesPEPTDispersion, 'Y')
            zlabel(app.UIAxesPEPTDispersion, 'Z')
            app.UIAxesParityDispersion = uiaxes(app.DispersionTab);
            xlabel(app.UIAxesParityDispersion, 'X')
            ylabel(app.UIAxesParityDispersion, 'Y')
            zlabel(app.UIAxesParityDispersion, 'Z')
            app.CFDLabelDispersion = uilabel(app.DispersionTab);
            app.CFDLabelDispersion.Text = 'CFD';
            app.PEPTLabelDispersion = uilabel(app.DispersionTab);
            app.PEPTLabelDispersion.Text = 'PEPT';
            app.ColourbarEditField_4Label = uilabel(app.DispersionTab);
            app.ColourbarEditField_4Label.Text = 'Colourbar';
            app.ColourbarEditField_4 = uieditfield(app.DispersionTab, 'numeric');
            app.ColourbarEditField_4.ValueChangedFcn = createCallbackFcn(app, @ColourbarEditField_4ValueChanged, true);
            app.ColourbarmaxEditField_4 = uieditfield(app.DispersionTab, 'numeric');
            app.ColourbarmaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @ColourbarmaxEditField_4ValueChanged, true);
            app.ColourbarmaxEditField_4.Value = 0.001;
            app.ParityplotEditField_3 = uieditfield(app.DispersionTab, 'numeric');
            app.ParityplotEditField_3.ValueChangedFcn = createCallbackFcn(app, @ParityplotEditField_3ValueChanged, true);
            app.ParityplotmaxEditField_3 = uieditfield(app.DispersionTab, 'numeric');
            app.ParityplotmaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @ParityplotmaxEditField_3ValueChanged, true);
            app.ParityplotmaxEditField_3.Value = 1;
            app.ParityplotEditField_3Label = uilabel(app.DispersionTab);
            app.ParityplotEditField_3Label.Text = 'Parity plot';
            app.ThresholdEditField_7 = uieditfield(app.DispersionTab, 'numeric');
            app.ThresholdEditField_7.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_7ValueChanged, true);
            app.MinLabel_5 = uilabel(app.DispersionTab);
            app.MinLabel_5.Text = 'Min';
            app.MaxLabel_5 = uilabel(app.DispersionTab);
            app.MaxLabel_5.Text = 'Max';
            app.CheckBox_6 = uicheckbox(app.DispersionTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.ThresholdEditField_8Label = uilabel(app.DispersionTab);
            app.ThresholdEditField_8Label.Text = 'Threshold';
            app.ThresholdEditField_8 = uieditfield(app.DispersionTab, 'numeric');
            app.ThresholdEditField_8.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_8ValueChanged, true);
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.UIAxesCFDDisplacement = uiaxes(app.DisplacementTab);
            xlabel(app.UIAxesCFDDisplacement, 'X')
            ylabel(app.UIAxesCFDDisplacement, 'Y')
            zlabel(app.UIAxesCFDDisplacement, 'Z')
            app.UIAxesPEPTDisplacement = uiaxes(app.DisplacementTab);
            xlabel(app.UIAxesPEPTDisplacement, 'X')
            ylabel(app.UIAxesPEPTDisplacement, 'Y')
            zlabel(app.UIAxesPEPTDisplacement, 'Z')
            app.UIAxesParityDisplacement = uiaxes(app.DisplacementTab);
            xlabel(app.UIAxesParityDisplacement, 'X')
            ylabel(app.UIAxesParityDisplacement, 'Y')
            zlabel(app.UIAxesParityDisplacement, 'Z')
            app.CFDLabelDisplacement = uilabel(app.DisplacementTab);
            app.CFDLabelDisplacement.Text = 'CFD';
            app.PEPTLabelDisplacement = uilabel(app.DisplacementTab);
            app.PEPTLabelDisplacement.Text = 'PEPT';
            app.ColourbarEditField_5Label = uilabel(app.DisplacementTab);
            app.ColourbarEditField_5Label.Text = 'Colourbar';
            app.ColourbarEditField_5 = uieditfield(app.DisplacementTab, 'numeric');
            app.ColourbarEditField_5.ValueChangedFcn = createCallbackFcn(app, @ColourbarEditField_5ValueChanged, true);
            app.ColourbarmaxEditField_3 = uieditfield(app.DisplacementTab, 'numeric');
            app.ColourbarmaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @ColourbarmaxEditField_3ValueChanged, true);
            app.ColourbarmaxEditField_3.Value = 0.1;
            app.ParityplotEditField_4Label = uilabel(app.DisplacementTab);
            app.ParityplotEditField_4Label.Text = 'Parity plot';
            app.ParityplotEditField_4 = uieditfield(app.DisplacementTab, 'numeric');
            app.ParityplotEditField_4.ValueChangedFcn = createCallbackFcn(app, @ParityplotEditField_4ValueChanged, true);
            app.ParityplotmaxEditField_4 = uieditfield(app.DisplacementTab, 'numeric');
            app.ParityplotmaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @ParityplotmaxEditField_4ValueChanged, true);
            app.ParityplotmaxEditField_4.Value = 1;
            app.ThresholdEditField_9 = uieditfield(app.DisplacementTab, 'numeric');
            app.ThresholdEditField_9.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_9ValueChanged, true);
            app.MinLabel_6 = uilabel(app.DisplacementTab);
            app.MinLabel_6.Text = 'Min';
            app.MaxLabel_6 = uilabel(app.DisplacementTab);
            app.MaxLabel_6.Text = 'Max';
            app.CheckBox_7 = uicheckbox(app.DisplacementTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.ThresholdEditField_10Label = uilabel(app.DisplacementTab);
            app.ThresholdEditField_10Label.Text = 'Threshold';
            app.ThresholdEditField_10 = uieditfield(app.DisplacementTab, 'numeric');
            app.ThresholdEditField_10.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_10ValueChanged, true);
            app.MixerEffectivenessTab = uitab(app.TabGroup);
            app.MixerEffectivenessTab.Title = 'Mixer Effectiveness';
            app.UIAxesCFDMixEff = uiaxes(app.MixerEffectivenessTab);
            xlabel(app.UIAxesCFDMixEff, 'X')
            ylabel(app.UIAxesCFDMixEff, 'Y')
            zlabel(app.UIAxesCFDMixEff, 'Z')
            app.UIAxesPEPTMixEff = uiaxes(app.MixerEffectivenessTab);
            xlabel(app.UIAxesPEPTMixEff, 'X')
            ylabel(app.UIAxesPEPTMixEff, 'Y')
            zlabel(app.UIAxesPEPTMixEff, 'Z')
            app.UIAxesParityMixEff = uiaxes(app.MixerEffectivenessTab);
            xlabel(app.UIAxesParityMixEff, 'X')
            ylabel(app.UIAxesParityMixEff, 'Y')
            zlabel(app.UIAxesParityMixEff, 'Z')
            app.CFDLabelMixEff = uilabel(app.MixerEffectivenessTab);
            app.CFDLabelMixEff.Text = 'CFD';
            app.PEPTLabelMixEff = uilabel(app.MixerEffectivenessTab);
            app.PEPTLabelMixEff.Text = 'PEPT';
            app.ColourbarEditField_6Label = uilabel(app.MixerEffectivenessTab);
            app.ColourbarEditField_6Label.Text = 'Colourbar';
            app.ColourbarEditField_6 = uieditfield(app.MixerEffectivenessTab, 'numeric');
            app.ColourbarEditField_6.ValueChangedFcn = createCallbackFcn(app, @ColourbarEditField_6ValueChanged, true);
            app.ColourbarmaxEditField_2 = uieditfield(app.MixerEffectivenessTab, 'numeric');
            app.ColourbarmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColourbarmaxEditField_2ValueChanged, true);
            app.ColourbarmaxEditField_2.Value = 0.5;
            app.ParityplotEditField_5Label = uilabel(app.MixerEffectivenessTab);
            app.ParityplotEditField_5Label.Text = 'Parity plot';
            app.ParityplotEditField_5 = uieditfield(app.MixerEffectivenessTab, 'numeric');
            app.ParityplotEditField_5.ValueChangedFcn = createCallbackFcn(app, @ParityplotEditField_5ValueChanged, true);
            app.ParityplotmaxEditField_5 = uieditfield(app.MixerEffectivenessTab, 'numeric');
            app.ParityplotmaxEditField_5.ValueChangedFcn = createCallbackFcn(app, @ParityplotmaxEditField_5ValueChanged, true);
            app.ParityplotmaxEditField_5.Value = 1;
            app.ThresholdEditField_11 = uieditfield(app.MixerEffectivenessTab, 'numeric');
            app.ThresholdEditField_11.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_11ValueChanged, true);
            app.MinLabel_7 = uilabel(app.MixerEffectivenessTab);
            app.MinLabel_7.Text = 'Min';
            app.MaxLabel_7 = uilabel(app.MixerEffectivenessTab);
            app.MaxLabel_7.Text = 'Max';
            app.CheckBox_8 = uicheckbox(app.MixerEffectivenessTab);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.ThresholdEditField_12Label = uilabel(app.MixerEffectivenessTab);
            app.ThresholdEditField_12Label.Text = 'Threshold';
            app.ThresholdEditField_12 = uieditfield(app.MixerEffectivenessTab, 'numeric');
            app.ThresholdEditField_12.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_12ValueChanged, true);
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.CharacteristicLengthmEditFieldLabel = uilabel(app.SettingsPanel);
            app.CharacteristicLengthmEditFieldLabel.Text = 'Characteristic Length (m)';
            app.CharacteristicLengthmEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.CharacteristicLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @CharacteristicLengthmEditFieldValueChanged, true);
            app.CharacteristicLengthmEditField.Value = 0.3;
            app.NumberofvoxelsEditFieldLabel = uilabel(app.SettingsPanel);
            app.NumberofvoxelsEditFieldLabel.Text = 'Number of voxels';
            app.NumberofvoxelsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.NumberofvoxelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofvoxelsEditFieldValueChanged2, true);
            app.NumberofvoxelsEditField.Value = 20;
            app.FreesurfacecutoffmEditFieldLabel = uilabel(app.SettingsPanel);
            app.FreesurfacecutoffmEditFieldLabel.Text = 'Free surface cut off (m)';
            app.FreesurfacecutoffmEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.FreesurfacecutoffmEditField.ValueChangedFcn = createCallbackFcn(app, @FreesurfacecutoffmEditFieldValueChanged, true);
            app.CFDdatacollectionstartsEditFieldLabel = uilabel(app.SettingsPanel);
            app.CFDdatacollectionstartsEditFieldLabel.Text = 'CFD data collection start (s)';
            app.CFDdatacollectionstartsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.CFDdatacollectionstartsEditField.ValueChangedFcn = createCallbackFcn(app, @CFDdatacollectionstartsEditFieldValueChanged, true);
            app.CFDdatacollectionstartsEditField.Value = 1;
            app.ofPEPTdatatoreadEditFieldLabel = uilabel(app.SettingsPanel);
            app.ofPEPTdatatoreadEditFieldLabel.Text = '% of PEPT data to read';
            app.ofPEPTdatatoreadEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ofPEPTdatatoreadEditField.ValueChangedFcn = createCallbackFcn(app, @ofPEPTdatatoreadEditFieldValueChanged, true);
            app.ofPEPTdatatoreadEditField.Value = 100;
            app.UpdateplotsButton = uibutton(app.SettingsPanel, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.CalculatevelocitiesButton = uibutton(app.SettingsPanel, 'push');
            app.CalculatevelocitiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatevelocitiesButtonPushed, true);
            app.CalculatevelocitiesButton.Text = 'Calculate velocities';
            app.PlotsettingsLabel = uilabel(app.SettingsPanel);
            app.PlotsettingsLabel.Text = 'Plot settings';
            app.DatasettingsLabel = uilabel(app.SettingsPanel);
            app.DatasettingsLabel.Text = 'Data settings';
            app.CheckBox = uicheckbox(app.SettingsPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ofCFDdatatoreadEditFieldLabel = uilabel(app.SettingsPanel);
            app.ofCFDdatatoreadEditFieldLabel.Text = '% of CFD data to read';
            app.ofCFDdatatoreadEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ofCFDdatatoreadEditField.ValueChangedFcn = createCallbackFcn(app, @ofCFDdatatoreadEditFieldValueChanged, true);
            app.ofCFDdatatoreadEditField.Value = 100;
            app.SavitzkyGolayFramelengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.SavitzkyGolayFramelengthEditFieldLabel.Text = 'Savitzky-Golay Framelength';
            app.SavitzkyGolayFramelengthEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SavitzkyGolayFramelengthEditField.ValueChangedFcn = createCallbackFcn(app, @SavitzkyGolayFramelengthEditFieldValueChanged, true);
            app.SavitzkyGolayFramelengthEditField.Value = 25;
            app.LampLabel = uilabel(app.SettingsPanel);
            app.LampLabel.Text = ' ';
            app.Lamp = uilamp(app.SettingsPanel);
            app.DispersiontimemaxsEditFieldLabel = uilabel(app.SettingsPanel);
            app.DispersiontimemaxsEditFieldLabel.Text = 'Dispersion time max (s)';
            app.DispersiontimemaxsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.DispersiontimemaxsEditField.ValueChangedFcn = createCallbackFcn(app, @DispersiontimemaxsEditFieldValueChanged, true);
            app.DispersiontimemaxsEditField.Value = 5;
            app.DispersiontimeintervalsEditFieldLabel = uilabel(app.SettingsPanel);
            app.DispersiontimeintervalsEditFieldLabel.Text = 'Dispersion time interval (s)';
            app.DispersiontimeintervalsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.DispersiontimeintervalsEditField.ValueChangedFcn = createCallbackFcn(app, @DispersiontimeintervalsEditFieldValueChanged, true);
            app.DispersiontimeintervalsEditField.Value = 0.5;
            app.CheckBox_2 = uicheckbox(app.SettingsPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.FreesurfacecutoffmEditFieldLabel_2 = uilabel(app.SettingsPanel);
            app.FreesurfacecutoffmEditFieldLabel_2.Text = 'Calculate Dispersion';
            app.VoxelsalphaEditFieldLabel = uilabel(app.SettingsPanel);
            app.VoxelsalphaEditFieldLabel.Text = 'Voxels alpha';
            app.VoxelsalphaEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.VoxelsalphaEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsalphaEditFieldValueChanged, true);
            app.VoxelsalphaEditField.Value = 0.2;
            app.xcalibcfd = uieditfield(app.SettingsPanel, 'numeric');
            app.xcalibcfd.ValueChangedFcn = createCallbackFcn(app, @xcalibcfdValueChanged, true);
            app.xcalibpept = uieditfield(app.SettingsPanel, 'numeric');
            app.xcalibpept.ValueChangedFcn = createCallbackFcn(app, @xcalibpeptValueChanged, true);
            app.zcalibpept = uieditfield(app.SettingsPanel, 'numeric');
            app.zcalibpept.ValueChangedFcn = createCallbackFcn(app, @zcalibpeptValueChanged, true);
            app.CFDLabel = uilabel(app.SettingsPanel);
            app.CFDLabel.Text = 'CFD';
            app.PEPTLabel = uilabel(app.SettingsPanel);
            app.PEPTLabel.Text = 'PEPT';
            app.zcalibcfd = uieditfield(app.SettingsPanel, 'numeric');
            app.zcalibcfd.ValueChangedFcn = createCallbackFcn(app, @zcalibcfdValueChanged, true);
            app.ycalibpept = uieditfield(app.SettingsPanel, 'numeric');
            app.ycalibpept.ValueChangedFcn = createCallbackFcn(app, @ycalibpeptValueChanged, true);
            app.ycalibcfd = uieditfield(app.SettingsPanel, 'numeric');
            app.ycalibcfd.ValueChangedFcn = createCallbackFcn(app, @ycalibcfdValueChanged, true);
            app.XLabel = uilabel(app.SettingsPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.SettingsPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.SettingsPanel);
            app.ZLabel.Text = 'Z';
            app.DatacentrepointLabel = uilabel(app.SettingsPanel);
            app.DatacentrepointLabel.Text = 'Data centre point';
            app.MaximumpassesforoutliersEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaximumpassesforoutliersEditFieldLabel.Text = 'Maximum passes for outliers';
            app.MaximumpassesforoutliersEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MaximumpassesforoutliersEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumpassesforoutliersEditFieldValueChanged, true);
            app.FlipPEPTx = uicheckbox(app.SettingsPanel);
            app.FlipPEPTx.ValueChangedFcn = createCallbackFcn(app, @FlipPEPTxValueChanged, true);
            app.FlipPEPTx.Text = '';
            app.FlipPEPTy = uicheckbox(app.SettingsPanel);
            app.FlipPEPTy.ValueChangedFcn = createCallbackFcn(app, @FlipPEPTyValueChanged, true);
            app.FlipPEPTy.Text = '';
            app.FlipPEPTz = uicheckbox(app.SettingsPanel);
            app.FlipPEPTz.ValueChangedFcn = createCallbackFcn(app, @FlipPEPTzValueChanged, true);
            app.FlipPEPTz.Text = '';
            app.FlipCFDx = uicheckbox(app.SettingsPanel);
            app.FlipCFDx.ValueChangedFcn = createCallbackFcn(app, @FlipCFDxValueChanged, true);
            app.FlipCFDx.Text = '';
            app.FlipCFDy = uicheckbox(app.SettingsPanel);
            app.FlipCFDy.ValueChangedFcn = createCallbackFcn(app, @FlipCFDyValueChanged, true);
            app.FlipCFDy.Text = '';
            app.FlipCFDz = uicheckbox(app.SettingsPanel);
            app.FlipCFDz.ValueChangedFcn = createCallbackFcn(app, @FlipCFDzValueChanged, true);
            app.FlipCFDz.Text = '';
            app.FlipdataLabel = uilabel(app.SettingsPanel);
            app.FlipdataLabel.Text = 'Flip data';
            app.CFDLabel_2 = uilabel(app.SettingsPanel);
            app.CFDLabel_2.Text = 'CFD';
            app.PEPTLabel_2 = uilabel(app.SettingsPanel);
            app.PEPTLabel_2.Text = 'PEPT';
            app.TransposedataLabel = uilabel(app.SettingsPanel);
            app.TransposedataLabel.Text = 'Transpose data';
            app.CFDTransposeZ = uibuttongroup(app.SettingsPanel);
            app.CFDTransposeZ.SelectionChangedFcn = createCallbackFcn(app, @CFDTransposeZSelectionChanged, true);
            app.CFDTransposeZx = uiradiobutton(app.CFDTransposeZ);
            app.CFDTransposeZx.Text = '';
            app.CFDTransposeZy = uiradiobutton(app.CFDTransposeZ);
            app.CFDTransposeZy.Text = '';
            app.CFDTransposeZz = uiradiobutton(app.CFDTransposeZ);
            app.CFDTransposeZz.Text = '';
            app.CFDTransposeZz.Value = true;
            app.CFDTransposeY = uibuttongroup(app.SettingsPanel);
            app.CFDTransposeY.SelectionChangedFcn = createCallbackFcn(app, @CFDTransposeYSelectionChanged, true);
            app.CFDTransposeYx = uiradiobutton(app.CFDTransposeY);
            app.CFDTransposeYx.Text = '';
            app.CFDTransposeYy = uiradiobutton(app.CFDTransposeY);
            app.CFDTransposeYy.Text = '';
            app.CFDTransposeYy.Value = true;
            app.CFDTransposeYz = uiradiobutton(app.CFDTransposeY);
            app.CFDTransposeYz.Text = '';
            app.CFDTransposeX = uibuttongroup(app.SettingsPanel);
            app.CFDTransposeX.SelectionChangedFcn = createCallbackFcn(app, @CFDTransposeXSelectionChanged, true);
            app.CFDTransposeXx = uiradiobutton(app.CFDTransposeX);
            app.CFDTransposeXx.Text = '';
            app.CFDTransposeXx.Value = true;
            app.CFDTransposeXy = uiradiobutton(app.CFDTransposeX);
            app.CFDTransposeXy.Text = '';
            app.CFDTransposeXz = uiradiobutton(app.CFDTransposeX);
            app.CFDTransposeXz.Text = '';
            app.PEPTTransposeZ = uibuttongroup(app.SettingsPanel);
            app.PEPTTransposeZ.SelectionChangedFcn = createCallbackFcn(app, @PEPTTransposeZSelectionChanged, true);
            app.PEPTTransposeZx = uiradiobutton(app.PEPTTransposeZ);
            app.PEPTTransposeZx.Text = '';
            app.PEPTTransposeZy = uiradiobutton(app.PEPTTransposeZ);
            app.PEPTTransposeZy.Text = '';
            app.PEPTTransposeZz = uiradiobutton(app.PEPTTransposeZ);
            app.PEPTTransposeZz.Text = '';
            app.PEPTTransposeZz.Value = true;
            app.PEPTTransposeY = uibuttongroup(app.SettingsPanel);
            app.PEPTTransposeY.SelectionChangedFcn = createCallbackFcn(app, @PEPTTransposeYSelectionChanged, true);
            app.PEPTTransposeYx = uiradiobutton(app.PEPTTransposeY);
            app.PEPTTransposeYx.Text = '';
            app.PEPTTransposeYy = uiradiobutton(app.PEPTTransposeY);
            app.PEPTTransposeYy.Text = '';
            app.PEPTTransposeYy.Value = true;
            app.PEPTTransposeYz = uiradiobutton(app.PEPTTransposeY);
            app.PEPTTransposeYz.Text = '';
            app.PEPTTransposeX = uibuttongroup(app.SettingsPanel);
            app.PEPTTransposeX.SelectionChangedFcn = createCallbackFcn(app, @PEPTTransposeXSelectionChanged, true);
            app.PEPTTransposeXx = uiradiobutton(app.PEPTTransposeX);
            app.PEPTTransposeXx.Text = '';
            app.PEPTTransposeXx.Value = true;
            app.PEPTTransposeXy = uiradiobutton(app.PEPTTransposeX);
            app.PEPTTransposeXy.Text = '';
            app.PEPTTransposeXz = uiradiobutton(app.PEPTTransposeX);
            app.PEPTTransposeXz.Text = '';
            app.PEPTLabel_3 = uilabel(app.SettingsPanel);
            app.PEPTLabel_3.Text = 'PEPT';
            app.CFDLabel_3 = uilabel(app.SettingsPanel);
            app.CFDLabel_3.Text = 'CFD';
            app.XLabel_2 = uilabel(app.SettingsPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.SettingsPanel);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.SettingsPanel);
            app.ZLabel_2.Text = 'Z';
            app.XLabel_3 = uilabel(app.SettingsPanel);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.SettingsPanel);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_3 = uilabel(app.SettingsPanel);
            app.ZLabel_3.Text = 'Z';
            app.XLabel_4 = uilabel(app.SettingsPanel);
            app.XLabel_4.Text = 'X';
            app.YLabel_4 = uilabel(app.SettingsPanel);
            app.YLabel_4.Text = 'Y';
            app.ZLabel_4 = uilabel(app.SettingsPanel);
            app.ZLabel_4.Text = 'Z';
            app.TomographyframeratefpsEditFieldLabel = uilabel(app.SettingsPanel);
            app.TomographyframeratefpsEditFieldLabel.Text = 'Tomography framerate (fps)';
            app.TomographyframeratefpsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TomographyframeratefpsEditField.ValueChangedFcn = createCallbackFcn(app, @TomographyframeratefpsEditFieldValueChanged, true);
            app.TomographyframeratefpsEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PEPTtoolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end